classdef demo5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button                          matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout16                    matlab.ui.container.GridLayout
        TabGroup_5                      matlab.ui.container.TabGroup
        Tab_12                          matlab.ui.container.Tab
        Panel_32                        matlab.ui.container.Panel
        endElEditField                  matlab.ui.control.NumericEditField
        endElEditFieldLabel             matlab.ui.control.Label
        initialElEditField              matlab.ui.control.NumericEditField
        initialElEditFieldLabel         matlab.ui.control.Label
        elLimitsEditField_2             matlab.ui.control.NumericEditField
        elLimitsEditField_2Label        matlab.ui.control.Label
        endAzEditField                  matlab.ui.control.NumericEditField
        endAzEditFieldLabel             matlab.ui.control.Label
        numScanPointsSpinner_2Label     matlab.ui.control.Label
        numScanPointsSpinner_2          matlab.ui.control.Spinner
        initialAzEditField              matlab.ui.control.NumericEditField
        initialAzEditFieldLabel         matlab.ui.control.Label
        azLimitsEditField_2             matlab.ui.control.NumericEditField
        azLimitsEditField_2Label        matlab.ui.control.Label
        numScanPointsAzSpinner_2        matlab.ui.control.Spinner
        numScanPointsAzSpinner_2Label   matlab.ui.control.Label
        numScanPointsElSpinner_2        matlab.ui.control.Spinner
        numScanPointsElSpinner_2Label   matlab.ui.control.Label
        scanEleStepEditField            matlab.ui.control.NumericEditField
        scanEleStepEditFieldLabel       matlab.ui.control.Label
        scanAziStepEditField_2          matlab.ui.control.NumericEditField
        scanAziStepEditField_2Label     matlab.ui.control.Label
        Panel_31                        matlab.ui.container.Panel
        beamStaring10DropDown           matlab.ui.control.DropDown
        beamStaring10DropDownLabel      matlab.ui.control.Label
        revisitTimesEditField           matlab.ui.control.NumericEditField
        revisitTimesEditFieldLabel      matlab.ui.control.Label
        elLimitsEditField               matlab.ui.control.NumericEditField
        elLimitsEditFieldLabel          matlab.ui.control.Label
        azLimitsEditField               matlab.ui.control.NumericEditField
        azLimitsEditFieldLabel          matlab.ui.control.Label
        mechanicalScanRatesEditField    matlab.ui.control.NumericEditField
        mechanicalScanRatesEditFieldLabel  matlab.ui.control.Label
        numScanPointsAzSpinner          matlab.ui.control.Spinner
        numScanPointsAzSpinnerLabel     matlab.ui.control.Label
        numScanPointsElSpinner          matlab.ui.control.Spinner
        numScanPointsElSpinnerLabel     matlab.ui.control.Label
        numScanPointsSpinner            matlab.ui.control.Spinner
        numScanPointsSpinnerLabel       matlab.ui.control.Label
        mScanCustomElAllEditField       matlab.ui.control.NumericEditField
        mScanCustomElAllEditFieldLabel  matlab.ui.control.Label
        mScanCustomAzAllEditField       matlab.ui.control.NumericEditField
        mScanCustomAzAllEditFieldLabel  matlab.ui.control.Label
        scanAziStepEditField            matlab.ui.control.NumericEditField
        scanAziStepEditFieldLabel       matlab.ui.control.Label
        electronicScanModeDropDown      matlab.ui.control.DropDown
        electronicScanModeDropDownLabel  matlab.ui.control.Label
        Panel_30                        matlab.ui.container.Panel
        mechanicalScanModeDropDown      matlab.ui.control.DropDown
        mechanicalScanModeDropDownLabel  matlab.ui.control.Label
        scanModeDropDown                matlab.ui.control.DropDown
        scanModeDropDownLabel           matlab.ui.control.Label
        Panel_29                        matlab.ui.container.Panel
        elevationWidthBWEditFieldLabel  matlab.ui.control.Label
        elevationWidthBWEditField       matlab.ui.control.NumericEditField
        arrayPowerGaindBCustomDropDown  matlab.ui.control.DropDown
        arrayPowerGaindBCustomDropDownLabel  matlab.ui.control.Label
        arrayStyleDropDown_3            matlab.ui.control.DropDown
        arrayStyleDropDown_3Label       matlab.ui.control.Label
        arraySizeEditField              matlab.ui.control.NumericEditField
        arraySizeEditFieldLabel         matlab.ui.control.Label
        azimuthWidthBWEditField_3Label  matlab.ui.control.Label
        azimuthWidthBWEditField_3       matlab.ui.control.NumericEditField
        arrayPowerGaindBdBEditField     matlab.ui.control.NumericEditField
        arrayPowerGaindBdBEditFieldLabel  matlab.ui.control.Label
        Panel_28                        matlab.ui.container.Panel
        r_fullgridEditFieldLabel        matlab.ui.control.Label
        r_fullgridEditField             matlab.ui.control.EditField
        t_fullgridEditFieldLabel        matlab.ui.control.Label
        t_fullgridEditField             matlab.ui.control.EditField
        rangeGateBlindNumSpinnerLabel   matlab.ui.control.Label
        rangeGateBlindNumSpinner        matlab.ui.control.Spinner
        rangeGateNumSpinnerLabel        matlab.ui.control.Label
        rangeGateNumSpinner             matlab.ui.control.Spinner
        Panel_27                        matlab.ui.container.Panel
        rangeGatesBlindmEditFieldLabel  matlab.ui.control.Label
        rangeGatesBlindmEditField       matlab.ui.control.NumericEditField
        rangeGatesmEditFieldLabel       matlab.ui.control.Label
        rangeGatesmEditField            matlab.ui.control.NumericEditField
        rangeRateResmsEditField         matlab.ui.control.NumericEditField
        rangeRateResmsEditFieldLabel    matlab.ui.control.Label
        dopResHzEditFieldLabel          matlab.ui.control.Label
        dopResHzEditField               matlab.ui.control.NumericEditField
        blindRangeBinSpinner            matlab.ui.control.Spinner
        blindRangeBinSpinnerLabel       matlab.ui.control.Label
        unambRangeBinSpinnerLabel       matlab.ui.control.Label
        unambRangeBinSpinner            matlab.ui.control.Spinner
        unambRadialSpdmsEditField       matlab.ui.control.NumericEditField
        unambRadialSpdmsEditFieldLabel  matlab.ui.control.Label
        blindRangemEditField            matlab.ui.control.NumericEditField
        blindRangemEditFieldLabel       matlab.ui.control.Label
        unambRangemEditField            matlab.ui.control.NumericEditField
        unambRangemEditFieldLabel       matlab.ui.control.Label
        sampleRangeResmEditField        matlab.ui.control.NumericEditField
        sampleRangeResmEditFieldLabel   matlab.ui.control.Label
        Panel_26                        matlab.ui.container.Panel
        receiverNoiseStyleFlag01kBTDropDown  matlab.ui.control.DropDown
        receiverNoiseStyleFlag01kBTDropDownLabel  matlab.ui.control.Label
        noisePowerWEditField            matlab.ui.control.NumericEditField
        noisePowerWEditFieldLabel       matlab.ui.control.Label
        temperatureKEditField           matlab.ui.control.NumericEditField
        temperatureKEditFieldLabel      matlab.ui.control.Label
        noiseFiguredBEditField          matlab.ui.control.NumericEditField
        noiseFiguredBEditFieldLabel     matlab.ui.control.Label
        Panel_25                        matlab.ui.container.Panel
        sampleRateHzEditFieldLabel      matlab.ui.control.Label
        sampleRateHzEditField           matlab.ui.control.NumericEditField
        rangeResmEditFieldLabel         matlab.ui.control.Label
        rangeResmEditField              matlab.ui.control.NumericEditField
        pulseTimeWidthsEditField        matlab.ui.control.NumericEditField
        pulseTimeWidthsEditFieldLabel   matlab.ui.control.Label
        cpiTimesEditField               matlab.ui.control.NumericEditField
        cpiTimesEditFieldLabel          matlab.ui.control.Label
        pulseBandWidthHzEditField       matlab.ui.control.NumericEditField
        pulseBandWidthHzEditFieldLabel  matlab.ui.control.Label
        pulsePRTsEditField              matlab.ui.control.NumericEditField
        pulsePRTsEditFieldLabel         matlab.ui.control.Label
        pulsePRFHzEditField             matlab.ui.control.NumericEditField
        pulsePRFHzEditFieldLabel        matlab.ui.control.Label
        Panel_24                        matlab.ui.container.Panel
        numPulsesSpinner                matlab.ui.control.Spinner
        numPulsesSpinnerLabel           matlab.ui.control.Label
        scanCircleNumSpinner            matlab.ui.control.Spinner
        scanCircleNumSpinnerLabel       matlab.ui.control.Label
        waveLenmEditField               matlab.ui.control.NumericEditField
        waveLenmEditFieldLabel          matlab.ui.control.Label
        peakPowerWEditField             matlab.ui.control.NumericEditField
        peakPowerWEditFieldLabel        matlab.ui.control.Label
        systemLossdBEditField           matlab.ui.control.NumericEditField
        systemLossdBEditFieldLabel      matlab.ui.control.Label
        carrierFreHzEditField           matlab.ui.control.NumericEditField
        carrierFreHzEditFieldLabel      matlab.ui.control.Label
        pulseSpeedmsEditField           matlab.ui.control.NumericEditField
        pulseSpeedmsEditFieldLabel      matlab.ui.control.Label
        Tab_13                          matlab.ui.container.Tab
        Panel_39                        matlab.ui.container.Panel
        platNameCellEditField           matlab.ui.control.EditField
        platNameCellEditFieldLabel      matlab.ui.control.Label
        Panel_38                        matlab.ui.container.Panel
        platformInitOrientationZYXEditFieldLabel  matlab.ui.control.Label
        platformInitOrientationZYXEditField  matlab.ui.control.EditField
        platformAngVelBodyradsEditField  matlab.ui.control.NumericEditField
        platformAngVelBodyradsEditFieldLabel  matlab.ui.control.Label
        Panel_37                        matlab.ui.container.Panel
        platformAccBodymsEditField      matlab.ui.control.NumericEditField
        platformAccBodymsEditFieldLabel  matlab.ui.control.Label
        platformPositionmxyzEditFieldLabel  matlab.ui.control.Label
        platformPositionmxyzEditField   matlab.ui.control.EditField
        platformInitVelmsEditField      matlab.ui.control.NumericEditField
        platformInitVelmsEditFieldLabel  matlab.ui.control.Label
        platformHeightmEditField        matlab.ui.control.NumericEditField
        platformHeightmEditFieldLabel   matlab.ui.control.Label
        Panel_36                        matlab.ui.container.Panel
        platformDimensionsLengthWidthHeightOriginOffsetEditFieldLabel  matlab.ui.control.Label
        platformDimensionsLengthWidthHeightOriginOffsetEditField  matlab.ui.control.EditField
        airplaneHmEditField             matlab.ui.control.NumericEditField
        airplaneHmEditFieldLabel        matlab.ui.control.Label
        airplaneWmEditField             matlab.ui.control.NumericEditField
        airplaneWmEditFieldLabel        matlab.ui.control.Label
        airplaneLmEditField             matlab.ui.control.NumericEditField
        airplaneLmEditFieldLabel        matlab.ui.control.Label
        Panel_35                        matlab.ui.container.Panel
        missileRadiusmEditField         matlab.ui.control.NumericEditField
        missileRadiusmEditFieldLabel    matlab.ui.control.Label
        missileHmEditField              matlab.ui.control.NumericEditField
        missileHmEditFieldLabel         matlab.ui.control.Label
        missileWmEditField              matlab.ui.control.NumericEditField
        missileWmEditFieldLabel         matlab.ui.control.Label
        missileLmEditField              matlab.ui.control.NumericEditField
        missileLmEditFieldLabel         matlab.ui.control.Label
        Panel_34                        matlab.ui.container.Panel
        platformSizeStyle12DropDown     matlab.ui.control.DropDown
        platformSizeStyle12DropDownLabel  matlab.ui.control.Label
        Panel_33                        matlab.ui.container.Panel
        platformMoveStyle12DropDown     matlab.ui.control.DropDown
        platformMoveStyle12DropDownLabel  matlab.ui.control.Label
        platformStyle12DropDown         matlab.ui.control.DropDown
        platformStyle12DropDownLabel    matlab.ui.control.Label
        platformMoveFlag1DropDown       matlab.ui.control.DropDown
        platformMoveFlag1DropDownLabel  matlab.ui.control.Label
        Tab_14                          matlab.ui.container.Tab
        Panel_41                        matlab.ui.container.Panel
        mountingLocationxyzmEditField   matlab.ui.control.EditField
        mountingLocationxyzmEditFieldLabel  matlab.ui.control.Label
        Panel_40                        matlab.ui.container.Panel
        mountingYPRYawPitchRollEditField  matlab.ui.control.EditField
        mountingYPRYawPitchRollEditFieldLabel  matlab.ui.control.Label
        Tab_2                           matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        TabGroup_6                      matlab.ui.container.TabGroup
        Tab_15                          matlab.ui.container.Tab
        GridLayout_3                    matlab.ui.container.GridLayout
        StopTimesEditField              matlab.ui.control.NumericEditField
        StopTimesEditFieldLabel         matlab.ui.control.Label
        IsEarthCenteredDropDown         matlab.ui.control.DropDown
        IsEarthCenteredDropDownLabel    matlab.ui.control.Label
        UpdateRateHzEditField           matlab.ui.control.NumericEditField
        UpdateRateHzEditFieldLabel      matlab.ui.control.Label
        Tab_7                           matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        TabGroup_7                      matlab.ui.container.TabGroup
        Tab_AntennaMount                matlab.ui.container.Tab
        GridLayout_4                    matlab.ui.container.GridLayout
        rdrpars                         matlab.ui.control.EditField
        rdrParsLabel                    matlab.ui.control.Label
        isShowFigDropDown               matlab.ui.control.DropDown
        isShowFigDropDownLabel          matlab.ui.control.Label
        arrayStyleDropDown_4            matlab.ui.control.DropDown
        arrayStyleDropDown_4Label       matlab.ui.control.Label
        Tab_AntennaConfig               matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_44                        matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        antEditField                    matlab.ui.control.EditField
        antEditFieldLabel               matlab.ui.control.Label
        PTextArea                       matlab.ui.control.TextArea
        PTextAreaLabel                  matlab.ui.control.Label
        mag_dBTextArea                  matlab.ui.control.TextArea
        mag_dBTextAreaLabel             matlab.ui.control.Label
        GTextArea                       matlab.ui.control.TextArea
        GTextAreaLabel                  matlab.ui.control.Label
        elEditField                     matlab.ui.control.EditField
        elEditFieldLabel                matlab.ui.control.Label
        azEditField                     matlab.ui.control.EditField
        azEditFieldLabel                matlab.ui.control.Label
        arrayURAEditField               matlab.ui.control.EditField
        arrayURAEditFieldLabel          matlab.ui.control.Label
        lambdamEditField                matlab.ui.control.NumericEditField
        lambdamEditFieldLabel           matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        bwEditField                     matlab.ui.control.EditField
        bwEditFieldLabel                matlab.ui.control.Label
        Panel_43                        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        sel                             matlab.ui.control.EditField
        selLabel                        matlab.ui.control.Label
        BasicParamsPanel                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        antennaElementEditField         matlab.ui.control.EditField
        antennaElementEditFieldLabel    matlab.ui.control.Label
        BackBaffledLabel                matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        arrayWeightsEditField           matlab.ui.control.EditField
        arrayWeightsEditFieldLabel      matlab.ui.control.Label
        arrayTextArea                   matlab.ui.control.TextArea
        arrayTextAreaLabel              matlab.ui.control.Label
        arrayAntennaNumEditField        matlab.ui.control.NumericEditField
        arrayAntennaNumEditFieldLabel   matlab.ui.control.Label
        arraySizePanel                  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        colsLabel                       matlab.ui.control.Label
        arrayColsLabel                  matlab.ui.control.NumericEditField
        arrayRowsLabel                  matlab.ui.control.NumericEditField
        rowsLabel                       matlab.ui.control.Label
        arrayElementSpacingmEditField   matlab.ui.control.NumericEditField
        arrayElementSpacingmEditFieldLabel  matlab.ui.control.Label
        Panel_42                        matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        arrayPowerGaindBCustomdBEditField  matlab.ui.control.NumericEditField
        arrayPowerGaindBCustomdBEditFieldLabel  matlab.ui.control.Label
        azimuthWidthBWEditField_4       matlab.ui.control.NumericEditField
        azimuthWidthBWEditField_4Label  matlab.ui.control.Label
        elemuthWidthBWEditField         matlab.ui.control.NumericEditField
        elemuthWidthBWEditFieldLabel    matlab.ui.control.Label
        arrayWeightsPlot2EditField      matlab.ui.control.EditField
        arrayWeightsPlot2EditFieldLabel  matlab.ui.control.Label
        Tab_16                          matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        Panel_46                        matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        selNormEditField                matlab.ui.control.EditField
        selNormEditFieldLabel           matlab.ui.control.Label
        selPosEditField                 matlab.ui.control.EditField
        selPosEditFieldLabel            matlab.ui.control.Label
        midCEditField                   matlab.ui.control.NumericEditField
        midCEditFieldLabel              matlab.ui.control.Label
        midREditField                   matlab.ui.control.NumericEditField
        midREditFieldLabel              matlab.ui.control.Label
        posEditField                    matlab.ui.control.EditField
        posEditFieldLabel               matlab.ui.control.Label
        verwidthEditField               matlab.ui.control.NumericEditField
        verwidthEditFieldLabel          matlab.ui.control.Label
        horwidthEditField               matlab.ui.control.NumericEditField
        horwidthEditFieldLabel          matlab.ui.control.Label
        colsEditField                   matlab.ui.control.NumericEditField
        colsEditFieldLabel              matlab.ui.control.Label
        rowsEditField                   matlab.ui.control.NumericEditField
        rowsEditFieldLabel              matlab.ui.control.Label
        Panel_45                        matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        subarraysNumEditField           matlab.ui.control.NumericEditField
        subarraysNumEditFieldLabel      matlab.ui.control.Label
        Tab_8                           matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_17                          matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        rdrtrajextraInfoEditField       matlab.ui.control.EditField
        rdrtrajextraInfoEditFieldLabel  matlab.ui.control.Label
        extraInfoAccelerationEditField  matlab.ui.control.EditField
        extraInfoAccelerationEditFieldLabel  matlab.ui.control.Label
        extraInfoOrientationEditField   matlab.ui.control.EditField
        extraInfoOrientationLabel       matlab.ui.control.Label
        initOrientationLabelqwqxqyqzEditField_2  matlab.ui.control.EditField
        initOrientationLabelqwqxqyqzEditFieldLabel_2  matlab.ui.control.Label
        angVelBodyxyzEditField          matlab.ui.control.EditField
        angVelBodyLabelxyzEditFieldLabel  matlab.ui.control.Label
        accBodyaxayazms2EditField       matlab.ui.control.EditField
        accBodyLabelaxayazms2EditFieldLabel  matlab.ui.control.Label
        extraInfoAccAngIsBodyFrameDropDown  matlab.ui.control.DropDown
        accAngIsBodyFrameLabelDropDownLabel  matlab.ui.control.Label
        rdrParssceneXYZMaxlxyzEditField  matlab.ui.control.EditField
        sceneXYZMaxLabelxmaxymaxzmaxmEditFieldLabel  matlab.ui.control.Label
        initVelvxvyvzEditField          matlab.ui.control.EditField
        initVelLabelvxvyvzEditFieldLabel  matlab.ui.control.Label
        initPosxyzmEditField            matlab.ui.control.EditField
        initPosLabelxyzmEditFieldLabel  matlab.ui.control.Label
        rdrtrajTextArea                 matlab.ui.control.TextArea
        rdrtrajTextAreaLabel            matlab.ui.control.Label
        trajCreationTypeDropDown        matlab.ui.control.DropDown
        trajCreationTypeDropDownLabel   matlab.ui.control.Label
        sampleTimeIntervalsEditField    matlab.ui.control.NumericEditField
        sampleTimeIntervalsLabel        matlab.ui.control.Label
        platformMoveStyleDropDown_2     matlab.ui.control.DropDown
        platformMoveStyleLabelDropDownLabel  matlab.ui.control.Label
        platformMoveFlagDropDown        matlab.ui.control.DropDown
        platformMoveFlagDropDownLabel   matlab.ui.control.Label
        Tab_11                          matlab.ui.container.Tab
        GridLayout_5                    matlab.ui.container.GridLayout
        DPanel                          matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        rdrplatPlatOtherParsnameStrEditField  matlab.ui.control.EditField
        rdrplatPlatOtherParsnameStrEditFieldLabel  matlab.ui.control.Label
        rdrplatPlatOtherParscurIndexEditField  matlab.ui.control.EditField
        rdrplatPlatOtherParscurIndexLabel  matlab.ui.control.Label
        rdrplatPlatOtherParscolorShowEditField  matlab.ui.control.EditField
        rdrplatPlatOtherParscolorShowEditFieldLabel  matlab.ui.control.Label
        rdrplatPlatOtherParscolorProportionEditField  matlab.ui.control.EditField
        rdrplatPlatOtherParscolorProportionEditFieldLabel  matlab.ui.control.Label
        rdrplatMeshVertices3EditField   matlab.ui.control.EditField
        rdrplatMeshVertices3EditFieldLabel  matlab.ui.control.Label
        RMEditField                     matlab.ui.control.EditField
        RMLabel                         matlab.ui.control.Label
        F3EditField                     matlab.ui.control.EditField
        F3EditFieldLabel                matlab.ui.control.Label
        V3EditField                     matlab.ui.control.EditField
        V3EditFieldLabel                matlab.ui.control.Label
        mesh3EditField                  matlab.ui.control.EditField
        mesh3EditFieldLabel             matlab.ui.control.Label
        platformSizeStyleDropDown_2     matlab.ui.control.DropDown
        platformSizeStyleDropDown_2Label  matlab.ui.control.Label
        Panel_47                        matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        rdrParsplatformDimensionsLWHEditField  matlab.ui.control.EditField
        rdrParsplatformDimensionsLWHEditFieldLabel  matlab.ui.control.Label
        fixedPositionLabelxyzEditField  matlab.ui.control.EditField
        fixedPositionLabelxyzEditFieldLabel  matlab.ui.control.Label
        useTrajectoryLabelTextDropDown  matlab.ui.control.DropDown
        useTrajectoryLabelTextDropDownLabel  matlab.ui.control.Label
        rdrParsrdrPlatClassIDClassIDEditField  matlab.ui.control.EditField
        rdrParsrdrPlatClassIDClassIDLabel  matlab.ui.control.Label
        Tab_19                          matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        Panel_48                        matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        iqsensorTransmitAntennaWeightsInputPortDropDown  matlab.ui.control.DropDown
        iqsensorTransmitAntennaWeightsInputPortLabel  matlab.ui.control.Label
        iqsensorTransmitAntennaSensorEditField  matlab.ui.control.EditField
        iqsensorTransmitAntennaSensorLabel  matlab.ui.control.Label
        iqsensorTransmitterCoherentOnTransmitDropDown  matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        iqsensorTransmitterGaindBEditField  matlab.ui.control.NumericEditField
        iqsensorTransmitterGaindBEditFieldLabel  matlab.ui.control.Label
        iqsensorTransmitAntennaOperatingFrequencyHzEditField  matlab.ui.control.NumericEditField
        iqsensorTransmitAntennaOperatingFrequencyHzLabel  matlab.ui.control.Label
        iqsensorTransmitterNoiseMethodDropDown  matlab.ui.control.DropDown
        iqsensorTransmitterNoiseMethodDropDownLabel  matlab.ui.control.Label
        iqsensorTransmitterPeakPowerWEditField_2  matlab.ui.control.NumericEditField
        iqsensorTransmitterPeakPowerWEditField_2Label  matlab.ui.control.Label
        iqsensorIQPanel                 matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        rdrParsoriginalLFMSignalPRTEditField  matlab.ui.control.EditField
        rdrParsoriginalLFMSignalPRTLabel  matlab.ui.control.Label
        rdrParsoriginalLFMWaveEditField  matlab.ui.control.EditField
        rdrParsoriginalLFMWaveLabel     matlab.ui.control.Label
        originalLFMWaveEditField        matlab.ui.control.EditField
        originalLFMWaveEditFieldLabel   matlab.ui.control.Label
        iqsensorWaveformLFMEditField    matlab.ui.control.EditField
        iqsensorWaveformLFMEditFieldLabel  matlab.ui.control.Label
        iqsensorprecisionDigitEditField  matlab.ui.control.NumericEditField
        iqsensorprecisionDigitEditFieldLabel  matlab.ui.control.Label
        iqsensorTimeOutputPortDropDown  matlab.ui.control.DropDown
        iqsensorTimeOutputPortDropDownLabel  matlab.ui.control.Label
        iqsensorRangeOutputPortDropDown  matlab.ui.control.DropDown
        iqsensorRangeOutputPortDropDownLabel  matlab.ui.control.Label
        iqsensorNumRepetitionsEditField  matlab.ui.control.NumericEditField
        iqsensorNumRepetitionsEditFieldLabel  matlab.ui.control.Label
        Tab_20                          matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        Panel_51                        matlab.ui.container.Panel
        GridLayout28                    matlab.ui.container.GridLayout
        iqsensorisElectronicSectorCPIFreezeCPIDropDown  matlab.ui.control.DropDown
        iqsensorisElectronicSectorCPIFreezeCPILabel  matlab.ui.control.Label
        iqsensorElectronicScanRatedegsEditField  matlab.ui.control.EditField
        iqsensorElectronicScanRatedegsEditFieldLabel  matlab.ui.control.Label
        iqsensorElectronicScanLimitsEditField  matlab.ui.control.EditField
        iqsensorElectronicScanLimitsEditFieldLabel  matlab.ui.control.Label
        iqsensorElectronicScanModeDropDown  matlab.ui.control.DropDown
        iqsensorElectronicScanModeDropDownLabel  matlab.ui.control.Label
        Panel_50                        matlab.ui.container.Panel
        GridLayout27_2                  matlab.ui.container.GridLayout
        iqsensorReceiveAntennaWeightsInputPortDropDown  matlab.ui.control.DropDown
        iqsensorReceiveAntennaWeightsInputPortDropDownLabel  matlab.ui.control.Label
        iqsensorReceiveAntennaSensorEditField  matlab.ui.control.EditField
        iqsensorReceiveAntennaSensorEditFieldLabel  matlab.ui.control.Label
        iqsensorReceiverGaindBEditField  matlab.ui.control.NumericEditField
        iqsensorReceiverGaindBEditFieldLabel  matlab.ui.control.Label
        iqsensorReceiverNoisePowerWEditField  matlab.ui.control.NumericEditField
        iqsensorReceiverNoisePowerWEditFieldLabel  matlab.ui.control.Label
        iqsensorReceiverNoiseFiguredBEditField  matlab.ui.control.NumericEditField
        iqsensorReceiverNoiseFiguredBEditFieldLabel  matlab.ui.control.Label
        iqsensorReceiverReferenceTemperatureKEditField_2  matlab.ui.control.NumericEditField
        iqsensorReceiverReferenceTemperatureKEditField_2Label  matlab.ui.control.Label
        iqsensorReceiverNoiseMethodDropDown_2  matlab.ui.control.DropDown
        iqsensorReceiverNoiseMethodDropDown_2Label  matlab.ui.control.Label
        rdrParsreceiverNoiseStyleFlagDropDown_2  matlab.ui.control.DropDown
        rdrParsreceiverNoiseStyleFlagDropDown_2Label  matlab.ui.control.Label
        iqsensorReceiverLossFactorEditField_2  matlab.ui.control.NumericEditField
        iqsensorReceiverLossFactorEditField_2Label  matlab.ui.control.Label
        iqsensorReceiveAntennaOperatingFrequencyHzEditField_2  matlab.ui.control.NumericEditField
        iqsensorReceiveAntennaOperatingFrequencyHzEditField_2Label  matlab.ui.control.Label
        iqsensorReceiverSampleRateHzEditField_2  matlab.ui.control.NumericEditField
        iqsensorReceiverSampleRateHzEditField_2Label  matlab.ui.control.Label
        Tab_9                           matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        Tab_18                          matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        landParsreflectModellandParsEditField  matlab.ui.control.EditField
        landParsreflectModellandParsEditFieldLabel  matlab.ui.control.Label
        reflectModelEditField           matlab.ui.control.EditField
        reflectModelEditFieldLabel      matlab.ui.control.Label
        terrainEditField                matlab.ui.control.EditField
        terrainEditFieldLabel           matlab.ui.control.Label
        landParsresSurfaceEditField     matlab.ui.control.NumericEditField
        landParsresSurfaceEditFieldLabel  matlab.ui.control.Label
        landParslandSurfaceHeightEditField  matlab.ui.control.NumericEditField
        landParslandSurfaceHeightEditFieldLabel  matlab.ui.control.Label
        landParslandSurfaceWidthEditField  matlab.ui.control.NumericEditField
        landParslandSurfaceWidthEditFieldLabel  matlab.ui.control.Label
        landParslandSurfaceBoundaryEditField  matlab.ui.control.NumericEditField
        landParslandSurfaceBoundaryEditFieldLabel  matlab.ui.control.Label
        landParsreflLandEditField       matlab.ui.control.NumericEditField
        landParsreflLandLabel           matlab.ui.control.Label
        gammaDBGammadBEditField         matlab.ui.control.NumericEditField
        gammaDBGammadBLabel             matlab.ui.control.Label
        operatingFrequencyHzEditField   matlab.ui.control.NumericEditField
        operatingFrequencyHzEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        rdrPars 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
                % Introduction to Radar Scenario Clutter Simulation
                %% 雷达参数初始化
                app.rdrpars.carrierFre = app.carrierFreHzEditField.Value;%HZ 载频
                if 1
                    app.rdrpars.pulsePRF = app.pulsePRFHzEditField.Value;%HZ 1e3 脉冲重复频率
                    app.rdrpars.pulsePRT = 1 / app.rdrpars.pulsePRF;%s  脉冲重复间隔
                else
                    app.rdrpars.pulsePRT = app.pulsePRTsEditField.Value;%s  40us~60us 脉冲重复间隔
                    app.rdrpars.pulsePRF = 1 / app.rdrpars.pulsePRT;%HZ 1e3 脉冲重复频率
                end
                % %避免出现用1.999999代替2.0；3.14000001代替3.14
                % app.rdrpars.pulsePRT = round(app.rdrpars.pulsePRT, 9);
                app.rdrpars.pulseTimeWidth = app.pulseTimeWidthsEditField.Value;%s 脉冲宽度
                % app.rdrpars.pulseTimeWidth = app.rdrpars.pulsePRT * 0.1;%s 1e-3 脉冲宽度 占空比5~10%
                if 1
                    %  5M Hz 10M 20M Hz
                    app.rdrpars.pulseBandWidth = app.pulseBandWidthHzEditField.Value;%app.rdrpars.pulsePRT * 0.08;%Hz rangeres2bw(app.rdrpars.rangeRes) 脉冲带宽
                    app.rdrpars.rangeRes = bw2rangeres(app.rdrpars.pulseBandWidth);%m 距离分辨率
                else
                    app.rdrpars.rangeRes = 10;%米
                    app.rdrpars.pulseBandWidth = rangeres2bw(app.rdrpars.rangeRes);%Hz
                end
                %采样率乘以PRT必须得是整数
                app.rdrpars.sampleRate = 2 * app.rdrpars.pulseBandWidth; %round(app.rdrpars.pulseBandWidth / app.rdrpars.pulsePRF) * app.rdrpars.pulsePRF;%%Hz % adjust to match constraint with PRF
                app.rdrpars.sampleRate = app.rdrpars.pulsePRF * round(app.rdrpars.sampleRate / app.rdrpars.pulsePRF); % adjust to match constraint with PRF
                app.rdrpars.sampleRangeRes = bw2rangeres(app.rdrpars.sampleRate);%m 采样距离分辨率
                app.rdrpars.peakPower = app.peakPowerWEditField.Value;%W  峰值功率  Units are in Watts.
                app.rdrpars.pulseSpeed = physconst('lightspeed');%电磁波传播速度 光速
                app.rdrpars.waveLen = freq2wavelen(app.rdrpars.carrierFre);%波长 米

                app.rdrpars.unambRange = time2range(app.rdrpars.pulsePRT);%不模糊距离 Simulate Radar Detections of Surface Targets in Clutter
                app.rdrpars.unambRangeBin = max(ceil(app.rdrpars.unambRange  / app.rdrpars.sampleRangeRes), 1);%不模糊距离对应的距离单元 Simulate Radar Detections of Surface Targets in Clutter
                app.rdrpars.unambRadialSpd = dop2speed(app.rdrpars.pulsePRF / 4, app.rdrpars.waveLen);%不模糊径向速度 Simulate Radar Detections of Surface Targets in Clutter
                app.rdrpars.blindRange = time2range(app.rdrpars.pulseTimeWidth);%盲距
                app.rdrpars.blindRangeBin = max(ceil(app.rdrpars.blindRange / app.rdrpars.sampleRangeRes), 1);%盲距对应的距离单元
                app.rdrpars.azimuthWidthBW = 6;%度  可以自己赋值，也可以根据天线阵列可以自动计算出来
                app.rdrpars.elevationWidthBW = 6;%度
                % app.rdrpars.scanningRate = 6;% r/min
                app.rdrpars.receiverNoiseStyleFlag = str2double(app.receiverNoiseStyleFlag01kBTDropDown.Items);%接收机热噪声类型 1表示使用kBT，0表示直接使用噪声功率(in Watts)
                app.rdrpars.noiseFigure = app.noiseFiguredBEditField.Value;%dB  接收机热噪声系数 A scalar containing the noise figure (in decibels) of the receiver preamp
                app.rdrpars.systemLoss = app.systemLossdBEditField.Value;%dB 接收机损失
                app.rdrpars.temperature = app.temperatureKEditField.Value;%雷达温度 参考温度默认是290 (in kelvin)
                % First assume that the noise bandwidth equals the sample rate of the receiver preamp. Then, the noise power is equal to:
                NoiseBandwidth = app.rdrpars.sampleRate;
                % The noise power is the variance of the additive white noise.
                noisepow = physconst('Boltzmann') * systemp(app.rdrpars.noiseFigure, app.rdrpars.temperature) * NoiseBandwidth;

                % app.rdrpars.noisePower = db2pow(-150);%噪声功率(in Watts) app.rdrpars.receiverNoiseStyleFlag = 0
                app.rdrpars.noisePower = app.noisePowerWEditField.Value;%噪声功率(in Watts) app.rdrpars.receiverNoiseStyleFlag = 0

                % app.rdrpars.antGain = 45.7; % Antenna gain (dB) 发射机、接收机 天线增益
                app.rdrpars.polarization = 'H';%%极化方式
                app.rdrpars.numPulses = app.numPulsesSpinner.Value;%脉冲数
                % app.rdrpars.rangeRateRes = app.rdrpars.waveLen / 2 * app.rdrpars.pulsePRF  / app.rdrpars.numPulses;%速度分辨率  距离变化率就是速度
                app.rdrpars.dopRes = app.rdrpars.pulsePRF  / app.rdrpars.numPulses;%多普勒分辨率
                app.rdrpars.rangeRateRes = dop2speed(app.rdrpars.dopRes, app.rdrpars.waveLen) / 2;%速度分辨率  距离变化率就是速度

                app.rdrpars.rangeGates = 0 : app.rdrpars.sampleRangeRes : (app.rdrpars.unambRange - app.rdrpars.sampleRangeRes);%距离单元对应的距离
                app.rdrpars.rangeGatesBlind = 0 : app.rdrpars.sampleRangeRes : (app.rdrpars.blindRange - app.rdrpars.sampleRangeRes);%盲距对应的距离
                app.rdrpars.rangeGateNum = length(app.rdrpars.rangeGates);%距离单元数
                app.rdrpars.rangeGateBlindNum = length(app.rdrpars.rangeGatesBlind);%盲距的距离单元数

                app.rdrpars.foldRangeFlag = true;%false 每个脉冲按照真实时间处理；true 每个脉冲都会收到所有距离上回波
                %range gate computation
                ftlen = round(app.rdrpars.sampleRate / app.rdrpars.pulsePRF);
                n_fullgrid = ftlen;
                app.rdrpars.t_fullgrid = (0:n_fullgrid-1).'/app.rdrpars.sampleRate;

                rstep = app.rdrpars.pulseSpeed / (2 * app.rdrpars.sampleRate);
                app.rdrpars.r_fullgrid = (0:n_fullgrid-1).'*rstep;

                app.rdrpars.scanMode = 'Electronic';%'Mechanical' (default) | 'Electronic' | 'Mechanical and electronic' | 'No scanning' | 'Custom'
                %扫描俯仰角-方位角
                %扫描的代码逻辑：在radarTransceiver.m，直接用长度判断是否扫描结束
                %例如：1到3度，扫描角度积累满2度后即停止扫描。
                % Azimuth angles must be less than or equal to 180 and elevation angles must be less than or equal to 90.
                % Expected each row of ElectronicScanLimits to be non-decreasing.只能递增
                app.rdrpars.initialEl = -12;%扫描起始俯仰角 正值表示水平往上，负值表示水平往下
                app.rdrpars.endEl = -12;%扫描结束俯仰角
                app.rdrpars.volumnEl = app.rdrpars.endEl - app.rdrpars.initialEl;%扫描的俯仰角
                % Azimuth angles must be less than or equal to 180 and elevation angles must be less than or equal to 90.
                % Expected each row of ElectronicScanLimits to be non-decreasing.只能递增
                app.rdrpars.initialAz = 0; %扫描起始方位角  0表示和X轴正向平行，正值表示往Y轴正向扫，即逆时钟转；负值相反
                app.rdrpars.endAz = 0; %扫描结束方位角
                app.rdrpars.volumnAz = app.rdrpars.endAz - app.rdrpars.initialAz;%扫描的方位角
                % 电扫sector的角度变化，默认的电扫sector的角度是随着脉冲数变化的
                % 例如间隔1度的时候，脉冲数为8，则这8个每次每次扫描间隔变化为1/8度。
                % 即设置iqsensor.isElectronicSectorCPIFreeze = false;%发射接收脉冲期间，电子扫描波束静止
                % 自己修改了radarTransceiver.m文件中代码，设置isElectronicSectorCPIFreeze为true时，一个CPI内不扫码，角度固定
                app.rdrpars.isElectronicSectorCPIFreeze = true;

                if app.rdrpars.isElectronicSectorCPIFreeze
                    %%波束扫描方位差和俯仰差
                    if app.rdrpars.initialAz == app.rdrpars.endAz
                        %驻留模式
                        app.rdrpars.scanAziStep = 0;
                        app.rdrpars.numScanPointsAz = 1;
                        app.rdrpars.azArrayStart = app.rdrpars.initialAz;
                        app.rdrpars.azArrayEnd = app.rdrpars.endAz;
                        app.rdrpars.azArrayTrue = app.rdrpars.endAz;%雷达扫描真正的角度
                    elseif app.rdrpars.initialAz < app.rdrpars.endAz%顺时针扫描
                        % app.rdrpars.scanAziStep = app.rdrpars.azimuthWidthBW / 2;%扫描的波束方位差
                        app.rdrpars.scanAziStep = 10;%扫描的波束方位差
                        app.rdrpars.azArray = app.rdrpars.initialAz : app.rdrpars.scanAziStep : app.rdrpars.endAz;
                        app.rdrpars.azArrayStart =  app.rdrpars.azArray(1);
                        app.rdrpars.azArrayEnd =  app.rdrpars.azArray(end);%最后一个扫描不上
                        app.rdrpars.numScanPointsAz = length(app.rdrpars.azArray) - 1;%最后一个扫描不上
                        app.rdrpars.azArrayTrue = app.rdrpars.initialAz : app.rdrpars.scanAziStep : app.rdrpars.endAz - app.rdrpars.scanAziStep;%雷达扫描真正的角度
                        % else
                        %     app.rdrpars.scanAziStep = -5;%扫描的波束方位差
                        %     app.rdrpars.azArray = app.rdrpars.initialAz : app.rdrpars.scanAziStep : app.rdrpars.endAz;
                        %     app.rdrpars.azArrayStart =  app.rdrpars.azArray(1);
                        %     app.rdrpars.azArrayEnd =  app.rdrpars.azArray(end);%最后一个扫描不上
                        %     app.rdrpars.numScanPointsAz = length(app.rdrpars.azArray) - 1;%最后一个扫描不上
                        %     app.rdrpars.azArrayTrue = app.rdrpars.initialAz : app.rdrpars.scanAziStep : app.rdrpars.endAz - app.rdrpars.scanAziStep;%雷达扫描真正的角度
                    end

                    if app.rdrpars.initialEl == app.rdrpars.endEl%俯仰上驻留
                        app.rdrpars.scanEleStep = 0;
                        app.rdrpars.numScanPointsEl = 1;
                        app.rdrpars.elArrayStart = app.rdrpars.initialEl;
                        app.rdrpars.elArrayEnd = app.rdrpars.endEl;
                        app.rdrpars.elArrayTrue = app.rdrpars.initialEl;%雷达扫描真正的角度
                    elseif app.rdrpars.initialEl < app.rdrpars.endEl%顺时针扫描
                        % app.rdrpars.scanEleStep = app.rdrpars.elevationWidthBW / 2;%扫描的波束俯仰差
                        app.rdrpars.scanEleStep = 10;%扫描的波束俯仰差 度
                        app.rdrpars.elArray = app.rdrpars.initialEl : app.rdrpars.scanEleStep : app.rdrpars.endEl;
                        app.rdrpars.elArrayStart = app.rdrpars.elArray(1);
                        app.rdrpars.elArrayEnd = app.rdrpars.elArray(end);%最后一个扫描不上
                        app.rdrpars.numScanPointsEl = length(app.rdrpars.elArray) - 1;%最后一个扫描不上
                        app.rdrpars.elArrayTrue = app.rdrpars.initialEl : app.rdrpars.scanEleStep : app.rdrpars.endEl - app.rdrpars.scanEleStep;%雷达扫描真正的角度
                        % else
                        %     app.rdrpars.scanEleStep = -1;%扫描的波束俯仰差 度
                        %     app.rdrpars.elArray = app.rdrpars.initialEl : app.rdrpars.scanEleStep : app.rdrpars.endEl;
                        %     app.rdrpars.elArrayStart = app.rdrpars.elArray(1);
                        %     app.rdrpars.elArrayEnd = app.rdrpars.elArray(end);%最后一个扫描不上
                        %     app.rdrpars.numScanPointsEl = length(app.rdrpars.elArray) - 1;%最后一个扫描不上
                        %     app.rdrpars.elArrayTrue = app.rdrpars.initialEl : app.rdrpars.scanEleStep : app.rdrpars.endEl - app.rdrpars.scanEleStep;%雷达扫描真正的角度
                    end
                else
                    %%波束扫描方位差和俯仰差
                    if app.rdrpars.initialAz == app.rdrpars.endAz
                        %驻留模式
                        app.rdrpars.scanAziStep = 0;
                        app.rdrpars.numScanPointsAz = 1;
                        app.rdrpars.azArrayStart = app.rdrpars.initialAz;
                        app.rdrpars.azArrayEnd = app.rdrpars.endAz;
                        app.rdrpars.azArrayTrue = app.rdrpars.endAz;%雷达扫描真正的角度
                    elseif app.rdrpars.initialAz < app.rdrpars.endAz%顺时针扫描
                        % app.rdrpars.scanAziStep = app.rdrpars.azimuthWidthBW / 2;%扫描的波束方位差
                        app.rdrpars.scanAziStep = 10;%扫描的波束方位差
                        app.rdrpars.azArray = app.rdrpars.initialAz : app.rdrpars.scanAziStep : app.rdrpars.endAz;
                        app.rdrpars.azArrayStart =  app.rdrpars.azArray(1);
                        app.rdrpars.azArrayEnd =  app.rdrpars.azArray(end);%最后一个扫描不上
                        app.rdrpars.numScanPointsAz = length(app.rdrpars.azArray) - 1;%最后一个扫描不上
                        app.rdrpars.azArrayTrue = app.rdrpars.initialAz : app.rdrpars.scanAziStep : app.rdrpars.endAz - app.rdrpars.scanAziStep;%雷达扫描真正的角度
                        app.rdrpars.scanAziStepCPI = linspace(0, app.rdrpars.scanAziStep, app.rdrpars.numPulses).';
                        app.rdrpars.scanAziStepCPI(end) = [];
                        app.rdrpars.azArrayTrueCPI = repmat(app.rdrpars.scanAziStepCPI, 1, length(app.rdrpars.azArrayTrue) - 1)...
                            + repmat(app.rdrpars.azArrayTrue(1 : end - 1), size(app.rdrpars.scanAziStepCPI, 1), 1);
                        app.rdrpars.azArrayTrueCPI = [app.rdrpars.azArrayTrueCPI(:); app.rdrpars.azArrayTrue(end)];
                        % else
                        %     app.rdrpars.scanAziStep = -5;%扫描的波束方位差
                        %     app.rdrpars.azArray = app.rdrpars.initialAz : app.rdrpars.scanAziStep : app.rdrpars.endAz;
                        %     app.rdrpars.azArrayStart =  app.rdrpars.azArray(1);
                        %     app.rdrpars.azArrayEnd =  app.rdrpars.azArray(end);%最后一个扫描不上
                        %     app.rdrpars.numScanPointsAz = length(app.rdrpars.azArray) - 1;%最后一个扫描不上
                        %     app.rdrpars.azArrayTrue = app.rdrpars.initialAz : app.rdrpars.scanAziStep : app.rdrpars.endAz - app.rdrpars.scanAziStep;%雷达扫描真正的角度
                    end

                    if app.rdrpars.initialEl == app.rdrpars.endEl%俯仰上驻留
                        app.rdrpars.scanEleStep = 0;
                        app.rdrpars.numScanPointsEl = 1;
                        app.rdrpars.elArrayStart = app.rdrpars.initialEl;
                        app.rdrpars.elArrayEnd = app.rdrpars.endEl;
                        app.rdrpars.elArrayTrue = app.rdrpars.initialEl;%雷达扫描真正的角度
                    elseif app.rdrpars.initialEl < app.rdrpars.endEl%顺时针扫描
                        % app.rdrpars.scanEleStep = app.rdrpars.elevationWidthBW / 2;%扫描的波束俯仰差
                        app.rdrpars.scanEleStep = 10;%扫描的波束俯仰差 度
                        app.rdrpars.elArray = app.rdrpars.initialEl : app.rdrpars.scanEleStep : app.rdrpars.endEl;
                        app.rdrpars.elArrayStart = app.rdrpars.elArray(1);
                        app.rdrpars.elArrayEnd = app.rdrpars.elArray(end);%最后一个扫描不上
                        app.rdrpars.numScanPointsEl = length(app.rdrpars.elArray) - 1;%最后一个扫描不上
                        app.rdrpars.elArrayTrue = app.rdrpars.initialEl : app.rdrpars.scanEleStep : app.rdrpars.endEl - app.rdrpars.scanEleStep;%雷达扫描真正的角度
                        app.rdrpars.scanEleStepCPI = linspace(0, app.rdrpars.scanEleStep, app.rdrpars.numPulses).';
                        app.rdrpars.scanEleStepCPI(end) = [];
                        app.rdrpars.azArrayTrueCPI = repmat(app.rdrpars.scanEleStepCPI, 1, length(app.rdrpars.elArrayTrue) - 1)...
                            + repmat(app.rdrpars.elArrayTrue(1 : end - 1), size(app.rdrpars.scanEleStepCPI, 1), 1);
                        app.rdrpars.azArrayTrueCPI = [app.rdrpars.azArrayTrueCPI(:); app.rdrpars.elArrayTrue(end)];
                        % else
                        %     app.rdrpars.scanEleStep = -1;%扫描的波束俯仰差 度
                        %     app.rdrpars.elArray = app.rdrpars.initialEl : app.rdrpars.scanEleStep : app.rdrpars.endEl;
                        %     app.rdrpars.elArrayStart = app.rdrpars.elArray(1);
                        %     app.rdrpars.elArrayEnd = app.rdrpars.elArray(end);%最后一个扫描不上
                        %     app.rdrpars.numScanPointsEl = length(app.rdrpars.elArray) - 1;%最后一个扫描不上
                        %     app.rdrpars.elArrayTrue = app.rdrpars.initialEl : app.rdrpars.scanEleStep : app.rdrpars.endEl - app.rdrpars.scanEleStep;%雷达扫描真正的角度
                    end
                end

                % iqsensor.ElectronicScanLimits = [app.rdrpars.azLimits; app.rdrpars.elLimits];
                app.rdrpars.azLimits = [app.rdrpars.azArrayStart, app.rdrpars.azArrayEnd]; %最后一个扫描不上
                app.rdrpars.elLimits = [app.rdrpars.elArrayStart, app.rdrpars.elArrayEnd];%最后一个扫描不上

                app.rdrpars.scanAziEleStep = [app.rdrpars.scanAziStep, app.rdrpars.scanEleStep];%每次扫描方位和俯仰移动的大小
                app.rdrpars.numScanPoints = app.rdrpars.numScanPointsAz * app.rdrpars.numScanPointsEl;
                if app.rdrpars.numScanPoints == 1
                    %驻留模式
                    app.rdrpars.beamStaring = 1;
                else
                    %扫描模式
                    app.rdrpars.beamStaring = 0;
                end
                allScanPulseNum = app.rdrpars.numPulses * app.rdrpars.numScanPoints;%总脉冲数

                % Calculate revisit time  计算重访时间
                app.rdrpars.revisitTime = allScanPulseNum / app.rdrpars.pulsePRF;%扫描完一次所有俯仰和方位所需的时间
                app.rdrpars.cpiTime = app.rdrpars.numPulses / app.rdrpars.pulsePRF;%一个脉冲相干积累时间
                app.rdrpars.scanCircleNum = 1;%雷达在感兴趣范围内扫描的圈数

                %Simulate a Scanning Radar https://www.mathworks.com/help/radar/ug/simulating-a-scanning-radar.html
                %机械扫描
                rpm = 12.5;%表示雷达天线的旋转速率，单位为每分钟旋转的圈数。
                app.rdrpars.mechanicalScanRate = rpm * 360 / 60; % deg/s  雷达扫描速率是以角度变化的速度（单位为度每秒 deg/s）来表示。
                % 'MechanicalScanMode','Circular'

                % 电子扫描的扫描速率，单位为度/秒，指定为二元列向量。第一行指定方位角方向的扫描速率，第二行指定仰角方向的扫描速率。
                % 'Sector' ‘扇形’–雷达扫描方向由电子扫描速率中指定的扫描速率和经过的时间决定。
                % 如果电子扫描限制仅沿方位角或仰角，则扫描角度从沿该维度的电子扫描限制的下端开始，并向扫描限制的上端扫描。
                % 当扫描达到电子扫描极限的上限时，它会再次从电子扫描极限的下限重新开始。
                % 如果电子扫描同时沿方位角和仰角进行，则扫描首先沿较长的维度进行，然后移动到较短维度的下一个位置。
                % 下面是方位长的情况
                % app.rdrpars.electronicScanRate = [app.rdrpars.volumnAz / (app.rdrpars.cpiTime * app.rdrpars.scanAziNum); ...
                %     app.rdrpars.volumnEl / (app.rdrpars.cpiTime * app.rdrpars.scanAziNum * app.rdrpars.scanEleNum)];%天线电扫时间间隔
                % 电扫sector的角度变化，默认的电扫sector的角度是随着脉冲数变化的
                % 例如间隔1度的时候，脉冲数为8，则这8个每次每次扫描间隔变化为1/8度。
                % 即设置iqsensor.isElectronicSectorCPIFreeze = false;%发射接收脉冲期间，电子扫描波束静止
                % escan_lim = [app.rdrpars.initialAz, app.rdrpars.endAz; app.rdrpars.initialEl, app.rdrpars.endEl];
                escan_lim = [app.rdrpars.azLimits; app.rdrpars.elLimits];
                escan_span = diff(escan_lim, 1, 2);
                if escan_span(1) == 0
                    % elevation only 只有俯仰电扫
                    app.rdrpars.electronicScanRate = [0; app.rdrpars.scanEleStep / app.rdrpars.cpiTime];%天线电扫时间间隔
                elseif escan_span(2) == 0
                    % azimuth only 只有方位电扫
                    app.rdrpars.electronicScanRate = [app.rdrpars.scanAziStep ./ app.rdrpars.cpiTime; 0];%天线电扫时间间隔
                else% both azimuth and elevation 同时方位和俯仰电扫
                    if escan_span(1) <= escan_span(2)
                        % scan elevation first 先扫描俯仰，后扫描方位
                        app.rdrpars.electronicScanRate = [app.rdrpars.scanAziStep ./ (app.rdrpars.cpiTime * app.rdrpars.numScanPointsEl); ...
                            app.rdrpars.scanEleStep / app.rdrpars.cpiTime];%天线电扫时间间隔
                    else
                        % scan azimuth first  先扫描方位，后扫描俯仰
                        app.rdrpars.electronicScanRate = [app.rdrpars.scanAziStep ./ app.rdrpars.cpiTime; ...
                            app.rdrpars.scanEleStep / (app.rdrpars.cpiTime * app.rdrpars.numScanPointsAz)];%天线电扫时间间隔
                    end
                end
                %这个参数极其重要，控制精度，防止matlab中的计算误差导致计算角度错误，例如本应该是5.0000，matlab计算出4.99999999999
                app.rdrpars.precisionDigit = 4;
                %%%%%%%%%%%%%%%%%%%配置天线阵列
                % isArray = 0;% 0 采用多阵元天线； 1 采用单个sinc、 2 采用单个高斯 3 自定义天线，该天线的方向图满足要求
                % app.rdrpars = arrayConfigure(app.rdrpars, isArray, isShowFig);
                app.rdrpars.arrayPowerGaindB = app.arrayPowerGaindBdBEditField.Value; %dB 单程增益 根据天线阵列可以自动计算出来，也可以自行指定
                app.rdrpars.arrayPowerGaindBCustom = str2double(app.arrayPowerGaindBCustomDropDown.Value);%是否自定义接收机和发射机的增益 1表示使用上面定义的值
                %%%%%%%%几何参数
                %雷达平台是否运动
                app.rdrpars.platformMoveFlag = app.platformMoveFlag1DropDown.Value;
                %雷达平台类型
                app.rdrpars.platformStyle = str2double(app.platformStyle12DropDown.Items);%1表示导弹;2表示飞机
                % 平台的classID
                app.rdrpars.rdrPlatClassID = 0;%雷达平台的classID
                app.rdrpars.tgtPlatClassID = 1;%目标平台的classID
                %雷达平台运动类型
                app.rdrpars.platformMoveStyle = str2double(app.platformMoveStyle12DropDown.Value);%1表示导弹或飞机弹道运行轨迹;2 沿某个方向运动
                %雷达平台尺寸
                app.rdrpars.platformSizeStyle = str2double(app.platformSizeStyle12DropDown.Value);%1表示点目标；2表示分布式目标
                if app.rdrpars.platformSizeStyle == 2
                    if app.rdrpars.platformStyle == 1%导弹
                        % For the radarTransceiver platform。For the radarDataGenerator, platform Length, Width, and Height are ignored 。
                        %导弹一般是圆柱体，这里用长方体代替
                        missileL = app.missileLmEditField.Value ;%米 Dimension of a cuboid along the x direction
                        missileW = app.missileWmEditField.Value;%米 Dimension of a cuboid along the y direction
                        missileH = app.missileHmEditField.Value;%米 Dimension of a cuboid along the z direction
                        % 如果平台原点在长方体 后表面 的中心，则设置OriginOffset为[-L/2，0，0]
                        % 原始的平台坐标系和场景坐标系是一样的
                        % Length	Dimension of a cuboid along the x direction
                        % Width	Dimension of a cuboid along the y direction
                        % Height	Dimension of a cuboid along the z direction
                        % OriginOffset	Position of the platform coordinate frame origin with respect to the cuboid center, specified as a vector of three elements
                        originOffset = [missileL / 2, 0, -missileH / 4];%	Position of the platform coordinate frame origin with respect to the cuboid center
                        app.rdrpars.missileRadius = missileW / 2;%导弹半径

                        %导引头雷达通常位于导弹最前端（弹头位置），确保视角开阔和导引精度
                        % 平台尺寸和原点偏移量，指定为结构。该结构包含长方体的长度、宽度、高度和初始偏移量，该长方体近似于平台的尺寸。
                        % OriginOffset是从长方体中心到平台坐标框架原点的位置向量。OriginOffset在平台坐标系中表示。
                        % 例如，如图所示，如果平台原点在长方体后表面的中心，则设置OriginOffset为[-L/2，0，0]。
                        % 尺寸的默认值是所有字段都设置为零的结构，这对应于点模型。
                        % Platform object belonging to radar scenario
                        app.rdrpars.platformDimensions = struct('Length',missileL,'Width',missileW,'Height',missileH,'OriginOffset', originOffset);%
                    elseif app.rdrpars.platformStyle == 2%飞机
                        % For the radarTransceiver platform。For the radarDataGenerator, platform Length, Width, and Height are ignored 。
                        %导弹一般是圆柱体，这里用长方体代替
                        airplaneL = app.airplaneLmEditField.Value;%米 Dimension of a cuboid along the x direction
                        airplaneW = app.airplaneWmEditField.Value;%米 Dimension of a cuboid along the y direction
                        airplaneH = app.airplaneHmEditField.Value;%米 Dimension of a cuboid along the z direction
                        % 如果平台原点在长方体 后表面 的中心，则设置OriginOffset为[-L/2，0，0]
                        % 原始的平台坐标系和场景坐标系是一样的
                        % Length	Dimension of a cuboid along the x direction
                        % Width	Dimension of a cuboid along the y direction
                        % Height	Dimension of a cuboid along the z direction
                        % OriginOffset	Position of the platform coordinate frame origin with respect to the cuboid center, specified as a vector of three elements
                        originOffset = [airplaneL / 2, 0, -airplaneH / 2];%	Position of the platform coordinate frame origin with respect to the cuboid center
                        % originOffset = [0, 0, 0];%	Position of the platform coordinate frame origin with respect to the cuboid center

                        %导引头雷达通常位于导弹最前端（弹头位置），确保视角开阔和导引精度
                        % 平台尺寸和原点偏移量，指定为结构。该结构包含长方体的长度、宽度、高度和初始偏移量，该长方体近似于平台的尺寸。
                        % OriginOffset是从长方体中心到平台坐标框架原点的位置向量。OriginOffset在平台坐标系中表示。
                        % 例如，如图所示，如果平台原点在长方体后表面的中心，则设置OriginOffset为[-L/2，0，0]。
                        % 尺寸的默认值是所有字段都设置为零的结构，这对应于点模型。
                        % Platform object belonging to radar scenario
                        app.rdrpars.platformDimensions = struct('Length',airplaneL,'Width',airplaneW,'Height',airplaneH,'OriginOffset', originOffset);%

                    end
                else
                    %雷达平台看作点目标
                    app.rdrpars.platformDimensions = struct('Length',0,'Width',0,'Height',0,'OriginOffset', [0,0,0]);%
                end
                %雷达所在平台的位置 仅在创建固定平台时需要指定Position，Orientation参数。
                app.rdrpars.platformHeight = app.platformHeightmEditField.Value;   % 雷达平台高度 Radar platform height (m)                                % Radar platform height (m)
                app.rdrpars.platformPosition = [0, 0, app.rdrpars.platformHeight]; % 雷达位置 Radar position [x y z] (m)
                %这里的速度是平台速度在场景坐标系X、Y、Z轴上的投影分量，已经投影过了，所以当速度不变时平台的角度不影响该速度
                % 如果想要实现实时根据平台方向的变化也出现速度的变化，则需要自己计算，并给出轨迹；或者再给出加速度和角加速度
                app.rdrpars.platformInitVel = [2000, 0, 0];%(m/s) 以场景坐标系为准
                app.rdrpars.platformAccBody = [0 0 0];%在场景坐标系中的加速度
                app.rdrpars.platformAngVelBody = [0 0 0];%在场景坐标系中的角速度
                app.rdrpars.platformInitOrientation = quaternion([0, 0, 0],'eulerd','zyx','frame');

                %天线在雷达平台的位置
                % 雷达相对于平台方向的安装角度，指定为围绕[z，y，x]轴的欧拉角中的1×3向量。这些角度也被称为[偏航、俯仰、滚转]角度。
                % 假设平台的方向由轴Xp、Yp和Zp定义。滚动角指定围绕Xp的逆时针旋转，俯仰角指定围绕Yp的逆时针旋转，偏航角指定围绕Zp的逆时针旋转。
                % 为了从平台的定向轴获得雷达的定向轴Xr、Yr和Zr，按照滚转、俯仰和偏航的顺序执行平台定向轴[Xp、Yp、Zp]的固有旋转。
                % The radar will face out the left side of the platform (90 degree yaw),
                % and down at the specified depression angle, with no roll about boresight.
                % 雷达将面向平台的左侧(90度偏航)，并以规定的俯角向下，没有绕视轴的滚转。
                %  depressionAngle = 18;%正值表示雷达平台沿水平面向下 Simulate Radar Detections of Surface Targets in Clutter
                % rdr.MountingAngles = [90 depressionAngle 0];%可以实现正视、侧视等效果
                app.rdrpars.mountingYPR = [0, 0, 0];
                app.rdrpars.mountingLocation = [0, 0, 0];%雷达原点与其安装平台原点的偏移。雷达在平台上装载的位置，平台有可能不是一个点，是一个有长宽高的物体
                %%%%%%海杂波所在范围，超出范围就没有杂波了
                app.rdrpars.clutterRangeLimit = 100e3;%米
                %%%地形数据
                app.rdrpars.terrainFile = [];%如果要使用地形数据，必须有地形数据文件
                %%%是否有地杂波
                app.rdrpars.isHasLandClutterFlag = 1;
                %%%是否有目标
                app.rdrpars.isHasTgtFlag = 1;
                %自己添加 均匀分割为1，非均匀分割为0
                app.rdrpars.surfaceSegFlag = 1;
                %是否边仿真边信号处理  该变量已被弃用
                app.rdrpars.isSigProFlag = 0;

                %%%保存数据的文件夹路径
                originalDataPath = './originalData/';
                if ~exist(originalDataPath, 'dir')
                    mkdir(originalDataPath);
                end
                app.rdrpars.originalDataPath = originalDataPath;

                %波束形成
                DBFDataPath = './DBFData/';
                if ~exist(DBFDataPath, 'dir')
                    mkdir(DBFDataPath);
                end
                app.rdrpars.DBFDataPath = DBFDataPath;
                %FFT
                FFTDataPath = './FFTData/';
                if ~exist(FFTDataPath, 'dir')
                    mkdir(FFTDataPath);
                end
                app.rdrpars.FFTDataPath = FFTDataPath;


                % 标记是动画1 还是仿真数据 0
                app.rdrpars.isShowSceneFlag = 0;
                % 仿真数据时是否画图 如果是1，则表示在仿真数据时画图；0表示不画图
                app.rdrpars.isShowSceneInSimDataFlag = 1;
                %%为了动画演示，所有时间需要加速，主要是雷达CPI太小，对于运动平台的观察不明显
                if ~app.rdrpars.isShowSceneFlag
                    app.rdrpars.timeSpeedFactor =  1;%正常CPI
                else
                    app.rdrpars.timeSpeedFactor =  1 / app.rdrpars.cpiTime;%加速CPI
                end
                app.rdrpars.cpiTime = app.rdrpars.cpiTime * app.rdrpars.timeSpeedFactor;
                % if escan_span(1) == 0
                %     % elevation only 只有俯仰电扫
                %     app.rdrpars.electronicScanRate = [0; app.rdrpars.scanEleStep / app.rdrpars.cpiTime];%天线电扫时间间隔
                % elseif escan_span(2) == 0
                %     % azimuth only 只有方位电扫
                %     app.rdrpars.electronicScanRate = [app.rdrpars.scanAziStep ./ app.rdrpars.cpiTime; 0];%天线电扫时间间隔
                % else% both azimuth and elevation 同时方位和俯仰电扫
                %     if escan_span(1) <= escan_span(2)
                %         % scan elevation first 先扫描俯仰，后扫描方位
                %         app.rdrpars.electronicScanRate = [app.rdrpars.scanAziStep ./ (app.rdrpars.cpiTime * app.rdrpars.numScanPointsEl); ...
                %             app.rdrpars.scanEleStep / app.rdrpars.cpiTime];%天线电扫时间间隔
                %     else
                %         % scan azimuth first  先扫描方位，后扫描俯仰
                %         app.rdrpars.electronicScanRate = [app.rdrpars.scanAziStep ./ app.rdrpars.cpiTime; ...
                %             app.rdrpars.scanEleStep / (app.rdrpars.cpiTime * app.rdrpars.numScanPointsAz)];%天线电扫时间间隔
                %     end
                % end
                app.rdrpars.electronicScanRate = app.rdrpars.electronicScanRate / app.rdrpars.timeSpeedFactor;
                % 保存画方向图的句柄
                app.rdrpars.plot3DPatternHCell = [];

                % 保存3维模型的路径
                app.rdrpars.threeDModelsPath = './3DModels/';

                % 保存平台的名字
                app.rdrpars.platNameCell = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '（三）参数初始化模块';
            app.GridLayout14 = uigridlayout(app.Tab);
            app.GridLayout16 = uigridlayout(app.GridLayout14);
            app.TabGroup_5 = uitabgroup(app.GridLayout16);
            app.Tab_12 = uitab(app.TabGroup_5);
            app.Tab_12.Title = '基本参数';
            app.Panel_24 = uipanel(app.Tab_12);
            app.Panel_24.Title = '雷达基本子模块';
            app.pulseSpeedmsEditFieldLabel = uilabel(app.Panel_24);
            app.pulseSpeedmsEditFieldLabel.Text = {'pulseSpeed(m/s)'; '电磁波传播速度'};
            app.pulseSpeedmsEditField = uieditfield(app.Panel_24, 'numeric');
            app.carrierFreHzEditFieldLabel = uilabel(app.Panel_24);
            app.carrierFreHzEditFieldLabel.Text = {'carrierFre(Hz)'; '载频'};
            app.carrierFreHzEditField = uieditfield(app.Panel_24, 'numeric');
            app.systemLossdBEditFieldLabel = uilabel(app.Panel_24);
            app.systemLossdBEditFieldLabel.Text = {'systemLoss(dB)'; '接收机损失'};
            app.systemLossdBEditField = uieditfield(app.Panel_24, 'numeric');
            app.peakPowerWEditFieldLabel = uilabel(app.Panel_24);
            app.peakPowerWEditFieldLabel.Text = {'peakPower(W)'; '峰值功率'};
            app.peakPowerWEditField = uieditfield(app.Panel_24, 'numeric');
            app.waveLenmEditFieldLabel = uilabel(app.Panel_24);
            app.waveLenmEditFieldLabel.Text = {'waveLen(m)'; '波长'};
            app.waveLenmEditField = uieditfield(app.Panel_24, 'numeric');
            app.scanCircleNumSpinnerLabel = uilabel(app.Panel_24);
            app.scanCircleNumSpinnerLabel.Text = {'scanCircleNum'; '扫描圈数'};
            app.scanCircleNumSpinner = uispinner(app.Panel_24);
            app.numPulsesSpinnerLabel = uilabel(app.Panel_24);
            app.numPulsesSpinnerLabel.Text = {'numPulses'; '脉冲数'};
            app.numPulsesSpinner = uispinner(app.Panel_24);
            app.Panel_25 = uipanel(app.Tab_12);
            app.Panel_25.Title = '信号与采样参数';
            app.pulsePRFHzEditFieldLabel = uilabel(app.Panel_25);
            app.pulsePRFHzEditFieldLabel.Text = {'pulsePRF(Hz)'; '脉冲重复频率'};
            app.pulsePRFHzEditField = uieditfield(app.Panel_25, 'numeric');
            app.pulsePRTsEditFieldLabel = uilabel(app.Panel_25);
            app.pulsePRTsEditFieldLabel.Text = {'pulsePRT(s)'; '脉冲重复间隔'};
            app.pulsePRTsEditField = uieditfield(app.Panel_25, 'numeric');
            app.pulseBandWidthHzEditFieldLabel = uilabel(app.Panel_25);
            app.pulseBandWidthHzEditFieldLabel.Text = {'pulseBandWidth(Hz)'; '脉冲带宽'};
            app.pulseBandWidthHzEditField = uieditfield(app.Panel_25, 'numeric');
            app.cpiTimesEditFieldLabel = uilabel(app.Panel_25);
            app.cpiTimesEditFieldLabel.Text = {'cpiTime(s)'; '相干处理时间'};
            app.cpiTimesEditField = uieditfield(app.Panel_25, 'numeric');
            app.pulseTimeWidthsEditFieldLabel = uilabel(app.Panel_25);
            app.pulseTimeWidthsEditFieldLabel.Text = {'pulseTimeWidth(s)'; '脉冲宽度'};
            app.pulseTimeWidthsEditField = uieditfield(app.Panel_25, 'numeric');
            app.rangeResmEditField = uieditfield(app.Panel_25, 'numeric');
            app.rangeResmEditFieldLabel = uilabel(app.Panel_25);
            app.rangeResmEditFieldLabel.Text = {'rangeRes(m)'; '距离分辨率'};
            app.sampleRateHzEditField = uieditfield(app.Panel_25, 'numeric');
            app.sampleRateHzEditFieldLabel = uilabel(app.Panel_25);
            app.sampleRateHzEditFieldLabel.Text = {'sampleRate(Hz)'; '采样率'};
            app.Panel_26 = uipanel(app.Tab_12);
            app.Panel_26.Title = '接收机与噪声参数';
            app.noiseFiguredBEditFieldLabel = uilabel(app.Panel_26);
            app.noiseFiguredBEditFieldLabel.Text = {'noiseFigure(dB)'; '噪声系数'};
            app.noiseFiguredBEditField = uieditfield(app.Panel_26, 'numeric');
            app.temperatureKEditFieldLabel = uilabel(app.Panel_26);
            app.temperatureKEditFieldLabel.Text = {'temperature(K)'; '接收机工作温度'};
            app.temperatureKEditField = uieditfield(app.Panel_26, 'numeric');
            app.noisePowerWEditFieldLabel = uilabel(app.Panel_26);
            app.noisePowerWEditFieldLabel.Text = {'noisePower(W)'; '噪声功率'};
            app.noisePowerWEditField = uieditfield(app.Panel_26, 'numeric');
            app.receiverNoiseStyleFlag01kBTDropDownLabel = uilabel(app.Panel_26);
            app.receiverNoiseStyleFlag01kBTDropDownLabel.Text = {'receiverNoiseStyleFlag'; '接收机噪声模型选择'; '(0=直接噪声功率,1=kBT 计算)'};
            app.receiverNoiseStyleFlag01kBTDropDown = uidropdown(app.Panel_26);
            app.receiverNoiseStyleFlag01kBTDropDown.Items = {'0', '1'};
            app.receiverNoiseStyleFlag01kBTDropDown.Value = '0';
            app.Panel_27 = uipanel(app.Tab_12);
            app.Panel_27.Title = '雷达基本子模块';
            app.sampleRangeResmEditFieldLabel = uilabel(app.Panel_27);
            app.sampleRangeResmEditFieldLabel.Text = {'sampleRangeRes(m)'; '采样距离分辨率'};
            app.sampleRangeResmEditField = uieditfield(app.Panel_27, 'numeric');
            app.unambRangemEditFieldLabel = uilabel(app.Panel_27);
            app.unambRangemEditFieldLabel.Text = {'unambRange(m)'; '不模糊距离'};
            app.unambRangemEditField = uieditfield(app.Panel_27, 'numeric');
            app.blindRangemEditFieldLabel = uilabel(app.Panel_27);
            app.blindRangemEditFieldLabel.Text = {'blindRange(m)'; '盲距'};
            app.blindRangemEditField = uieditfield(app.Panel_27, 'numeric');
            app.unambRadialSpdmsEditFieldLabel = uilabel(app.Panel_27);
            app.unambRadialSpdmsEditFieldLabel.Text = {'unambRadialSpd(m/s)'; '不模糊径向速度'};
            app.unambRadialSpdmsEditField = uieditfield(app.Panel_27, 'numeric');
            app.unambRangeBinSpinner = uispinner(app.Panel_27);
            app.unambRangeBinSpinnerLabel = uilabel(app.Panel_27);
            app.unambRangeBinSpinnerLabel.Text = {'unambRangeBin'; '不模糊距离对应距离单元数'};
            app.blindRangeBinSpinnerLabel = uilabel(app.Panel_27);
            app.blindRangeBinSpinnerLabel.Text = {'blindRangeBin'; '盲距对应的距离单元数'};
            app.blindRangeBinSpinner = uispinner(app.Panel_27);
            app.dopResHzEditField = uieditfield(app.Panel_27, 'numeric');
            app.dopResHzEditFieldLabel = uilabel(app.Panel_27);
            app.dopResHzEditFieldLabel.Text = {'dopRes(Hz)'; '多普勒分辨率'};
            app.rangeRateResmsEditFieldLabel = uilabel(app.Panel_27);
            app.rangeRateResmsEditFieldLabel.Text = {'rangeRateRes(m/s)'; '速度分辨率'};
            app.rangeRateResmsEditField = uieditfield(app.Panel_27, 'numeric');
            app.rangeGatesmEditField = uieditfield(app.Panel_27, 'numeric');
            app.rangeGatesmEditFieldLabel = uilabel(app.Panel_27);
            app.rangeGatesmEditFieldLabel.Text = {'rangeGates(m)'; '所有距离单元对应的距离'};
            app.rangeGatesBlindmEditField = uieditfield(app.Panel_27, 'numeric');
            app.rangeGatesBlindmEditFieldLabel = uilabel(app.Panel_27);
            app.rangeGatesBlindmEditFieldLabel.Text = {'rangeGatesBlind(m)'; '盲区对应的距离'};
            app.Panel_28 = uipanel(app.Tab_12);
            app.rangeGateNumSpinner = uispinner(app.Panel_28);
            app.rangeGateNumSpinnerLabel = uilabel(app.Panel_28);
            app.rangeGateNumSpinnerLabel.Text = {'rangeGateNum'; '距离单元数'};
            app.rangeGateBlindNumSpinner = uispinner(app.Panel_28);
            app.rangeGateBlindNumSpinnerLabel = uilabel(app.Panel_28);
            app.rangeGateBlindNumSpinnerLabel.Text = {'rangeGateBlindNum'; '盲区单元数'};
            app.t_fullgridEditField = uieditfield(app.Panel_28, 'text');
            app.t_fullgridEditFieldLabel = uilabel(app.Panel_28);
            app.t_fullgridEditFieldLabel.Text = {'t_fullgrid'; '采样时间网格'};
            app.r_fullgridEditField = uieditfield(app.Panel_28, 'text');
            app.r_fullgridEditFieldLabel = uilabel(app.Panel_28);
            app.r_fullgridEditFieldLabel.Text = {'r_fullgrid'; '采样距离网格'};
            app.Panel_29 = uipanel(app.Tab_12);
            app.Panel_29.Title = '天线与波束参数';
            app.arrayPowerGaindBdBEditFieldLabel = uilabel(app.Panel_29);
            app.arrayPowerGaindBdBEditFieldLabel.Text = {'arrayPowerGaindB(dB)'; '单程增益'};
            app.arrayPowerGaindBdBEditField = uieditfield(app.Panel_29, 'numeric');
            app.azimuthWidthBWEditField_3 = uieditfield(app.Panel_29, 'numeric');
            app.azimuthWidthBWEditField_3Label = uilabel(app.Panel_29);
            app.azimuthWidthBWEditField_3Label.Text = {'azimuthWidthBW(°)'; '方位向波束宽度'};
            app.arraySizeEditFieldLabel = uilabel(app.Panel_29);
            app.arraySizeEditFieldLabel.Text = {'arraySize'; '阵列尺寸'};
            app.arraySizeEditField = uieditfield(app.Panel_29, 'numeric');
            app.arrayStyleDropDown_3Label = uilabel(app.Panel_29);
            app.arrayStyleDropDown_3Label.Text = {'arrayStyle'; '天线阵列形式'};
            app.arrayStyleDropDown_3 = uidropdown(app.Panel_29);
            app.arrayStyleDropDown_3.Items = {'矩形', '线阵', '子阵', '十字阵'};
            app.arrayStyleDropDown_3.Value = '矩形';
            app.arrayPowerGaindBCustomDropDownLabel = uilabel(app.Panel_29);
            app.arrayPowerGaindBCustomDropDownLabel.Text = {'arrayPowerGaindBCust'; 'om'; '是否自定义收发增益'};
            app.arrayPowerGaindBCustomDropDown = uidropdown(app.Panel_29);
            app.arrayPowerGaindBCustomDropDown.Items = {'0', '1'};
            app.arrayPowerGaindBCustomDropDown.Value = '0';
            app.elevationWidthBWEditField = uieditfield(app.Panel_29, 'numeric');
            app.elevationWidthBWEditFieldLabel = uilabel(app.Panel_29);
            app.elevationWidthBWEditFieldLabel.Text = {'elevationWidthBW(°)'; '俯仰向波束宽度'};
            app.Panel_30 = uipanel(app.Tab_12);
            app.Panel_30.Title = '天线与波束参数';
            app.scanModeDropDownLabel = uilabel(app.Panel_30);
            app.scanModeDropDownLabel.Text = {'scanMode'; '扫描模式'};
            app.scanModeDropDown = uidropdown(app.Panel_30);
            app.scanModeDropDown.Items = {'Mechanical', 'Electronic'};
            app.scanModeDropDown.Value = 'Mechanical';
            app.mechanicalScanModeDropDownLabel = uilabel(app.Panel_30);
            app.mechanicalScanModeDropDownLabel.Text = {'mechanicalScanMode'; '机械扫描方式'};
            app.mechanicalScanModeDropDown = uidropdown(app.Panel_30);
            app.mechanicalScanModeDropDown.Items = {'None', 'Circular', 'Sector', 'Custom'};
            app.mechanicalScanModeDropDown.Value = 'None';
            app.electronicScanModeDropDownLabel = uilabel(app.Tab_12);
            app.electronicScanModeDropDownLabel.Text = {'electronicScanMode'; '电子扫描方式'};
            app.electronicScanModeDropDown = uidropdown(app.Tab_12);
            app.electronicScanModeDropDown.Items = {'None', 'Sector', 'Custom'};
            app.electronicScanModeDropDown.Value = 'None';
            app.Panel_31 = uipanel(app.Tab_12);
            app.Panel_31.Title = '机械扫描时设置';
            app.scanAziStepEditFieldLabel = uilabel(app.Panel_31);
            app.scanAziStepEditFieldLabel.Text = {'scanAziStep(°)'; '方位角步进'};
            app.scanAziStepEditField = uieditfield(app.Panel_31, 'numeric');
            app.mScanCustomAzAllEditFieldLabel = uilabel(app.Panel_31);
            app.mScanCustomAzAllEditFieldLabel.Text = {'mScanCustomAzAll(°)'; '自定义扫描方位角序列'};
            app.mScanCustomAzAllEditField = uieditfield(app.Panel_31, 'numeric');
            app.mScanCustomElAllEditFieldLabel = uilabel(app.Panel_31);
            app.mScanCustomElAllEditFieldLabel.Text = {'mScanCustomElAll(°)'; '自定义扫描俯仰角序列'};
            app.mScanCustomElAllEditField = uieditfield(app.Panel_31, 'numeric');
            app.numScanPointsSpinnerLabel = uilabel(app.Panel_31);
            app.numScanPointsSpinnerLabel.Text = {'numScanPoints'; '扫描点数'};
            app.numScanPointsSpinner = uispinner(app.Panel_31);
            app.numScanPointsElSpinnerLabel = uilabel(app.Panel_31);
            app.numScanPointsElSpinnerLabel.Text = {'numScanPointsEl'; '俯仰方向扫描点数'};
            app.numScanPointsElSpinner = uispinner(app.Panel_31);
            app.numScanPointsAzSpinnerLabel = uilabel(app.Panel_31);
            app.numScanPointsAzSpinnerLabel.Text = {'numScanPointsAz'; '方位方向扫描点数'};
            app.numScanPointsAzSpinner = uispinner(app.Panel_31);
            app.mechanicalScanRatesEditFieldLabel = uilabel(app.Panel_31);
            app.mechanicalScanRatesEditFieldLabel.Text = {'mechanicalScanRate(°/s)'; '机械扫描速率'};
            app.mechanicalScanRatesEditField = uieditfield(app.Panel_31, 'numeric');
            app.azLimitsEditFieldLabel = uilabel(app.Panel_31);
            app.azLimitsEditFieldLabel.Text = {'azLimits(°)'; '扫描方位范围'};
            app.azLimitsEditField = uieditfield(app.Panel_31, 'numeric');
            app.elLimitsEditFieldLabel = uilabel(app.Panel_31);
            app.elLimitsEditFieldLabel.Text = {'elLimits(°)'; '扫描俯仰范围'};
            app.elLimitsEditField = uieditfield(app.Panel_31, 'numeric');
            app.revisitTimesEditFieldLabel = uilabel(app.Panel_31);
            app.revisitTimesEditFieldLabel.Text = {'revisitTime(s)'; '重访时间'};
            app.revisitTimesEditField = uieditfield(app.Panel_31, 'numeric');
            app.beamStaring10DropDownLabel = uilabel(app.Panel_31);
            app.beamStaring10DropDownLabel.Text = {'beamStaring'; '是否驻留模式(1=驻留，0=扫描)'};
            app.beamStaring10DropDown = uidropdown(app.Panel_31);
            app.beamStaring10DropDown.Items = {'0', '1'};
            app.beamStaring10DropDown.Value = '0';
            app.Panel_32 = uipanel(app.Tab_12);
            app.Panel_32.Title = '电子扫描时设置';
            app.Panel_32.Visible = 'off';
            app.scanAziStepEditField_2Label = uilabel(app.Panel_32);
            app.scanAziStepEditField_2Label.Text = {'scanAziStep(°)'; '方位角扫描间隔'};
            app.scanAziStepEditField_2 = uieditfield(app.Panel_32, 'numeric');
            app.scanEleStepEditFieldLabel = uilabel(app.Panel_32);
            app.scanEleStepEditFieldLabel.Text = {'scanEleStep(°)'; '俯仰角扫描间隔'};
            app.scanEleStepEditField = uieditfield(app.Panel_32, 'numeric');
            app.numScanPointsElSpinner_2Label = uilabel(app.Panel_32);
            app.numScanPointsElSpinner_2Label.Text = {'numScanPointsEl'; '每圈俯仰角扫描数'};
            app.numScanPointsElSpinner_2 = uispinner(app.Panel_32);
            app.numScanPointsAzSpinner_2Label = uilabel(app.Panel_32);
            app.numScanPointsAzSpinner_2Label.Text = {'numScanPointsAz'; '每圈方位角扫描数'};
            app.numScanPointsAzSpinner_2 = uispinner(app.Panel_32);
            app.azLimitsEditField_2Label = uilabel(app.Panel_32);
            app.azLimitsEditField_2Label.Text = {'azLimits(°)'; '方位角扫描范围'};
            app.azLimitsEditField_2 = uieditfield(app.Panel_32, 'numeric');
            app.initialAzEditFieldLabel = uilabel(app.Panel_32);
            app.initialAzEditFieldLabel.Text = {'initialAz(°)'; '起始方位角'};
            app.initialAzEditField = uieditfield(app.Panel_32, 'numeric');
            app.numScanPointsSpinner_2 = uispinner(app.Panel_32);
            app.numScanPointsSpinner_2Label = uilabel(app.Panel_32);
            app.numScanPointsSpinner_2Label.Text = {'numScanPoints'; '每圈总扫描点数'};
            app.endAzEditFieldLabel = uilabel(app.Panel_32);
            app.endAzEditFieldLabel.Text = {'endAz(°)'; '终止方位角'};
            app.endAzEditField = uieditfield(app.Panel_32, 'numeric');
            app.elLimitsEditField_2Label = uilabel(app.Panel_32);
            app.elLimitsEditField_2Label.Text = {'elLimits(°)'; '俯仰角扫描范围'};
            app.elLimitsEditField_2 = uieditfield(app.Panel_32, 'numeric');
            app.initialElEditFieldLabel = uilabel(app.Panel_32);
            app.initialElEditFieldLabel.Text = {'initialEl(°)'; '起始俯仰角'};
            app.initialElEditField = uieditfield(app.Panel_32, 'numeric');
            app.endElEditFieldLabel = uilabel(app.Panel_32);
            app.endElEditFieldLabel.Text = {'endEl(°)'; '终止俯仰角'};
            app.endElEditField = uieditfield(app.Panel_32, 'numeric');
            app.Tab_13 = uitab(app.TabGroup_5);
            app.Tab_13.Title = '平台参数';
            app.Panel_33 = uipanel(app.Tab_13);
            app.Panel_33.Title = '平台运动与类型';
            app.platformMoveFlag1DropDownLabel = uilabel(app.Panel_33);
            app.platformMoveFlag1DropDownLabel.Text = {'platformMoveFlag'; '雷达平台是否运动(1:运动)'};
            app.platformMoveFlag1DropDown = uidropdown(app.Panel_33);
            app.platformMoveFlag1DropDown.Items = {'0', '1'};
            app.platformMoveFlag1DropDown.Value = '1';
            app.platformStyle12DropDownLabel = uilabel(app.Panel_33);
            app.platformStyle12DropDownLabel.Text = {'platformStyle'; '平台类型(1 = 导弹，2 = 飞机)'};
            app.platformStyle12DropDown = uidropdown(app.Panel_33);
            app.platformStyle12DropDown.Items = {'1', '2'};
            app.platformStyle12DropDown.Value = '1';
            app.platformMoveStyle12DropDownLabel = uilabel(app.Panel_33);
            app.platformMoveStyle12DropDownLabel.Text = {'platformMoveStyle'; '运动方式(1 = 弹道轨迹'; '2 = 沿某方向运动)'};
            app.platformMoveStyle12DropDown = uidropdown(app.Panel_33);
            app.platformMoveStyle12DropDown.Items = {'1', '2'};
            app.platformMoveStyle12DropDown.Value = '1';
            app.Panel_34 = uipanel(app.Tab_13);
            app.Panel_34.Title = '平台尺寸与几何';
            app.platformSizeStyle12DropDownLabel = uilabel(app.Panel_34);
            app.platformSizeStyle12DropDownLabel.Text = {'platformSizeStyle'; '平台尺寸类型(1 = 点目标'; '2 = 分布式目标)'};
            app.platformSizeStyle12DropDown = uidropdown(app.Panel_34);
            app.platformSizeStyle12DropDown.Items = {'1', '2'};
            app.platformSizeStyle12DropDown.Value = '1';
            app.Panel_35 = uipanel(app.Tab_13);
            app.Panel_35.Title = '导弹尺寸';
            app.missileLmEditFieldLabel = uilabel(app.Panel_35);
            app.missileLmEditFieldLabel.Text = {'missileL(m)'; '导弹近似长方体长度'};
            app.missileLmEditField = uieditfield(app.Panel_35, 'numeric');
            app.missileWmEditFieldLabel = uilabel(app.Panel_35);
            app.missileWmEditFieldLabel.Text = {'missileW(m)'; '宽度'};
            app.missileWmEditField = uieditfield(app.Panel_35, 'numeric');
            app.missileHmEditFieldLabel = uilabel(app.Panel_35);
            app.missileHmEditFieldLabel.Text = {'missileH(m)'; '高度'};
            app.missileHmEditField = uieditfield(app.Panel_35, 'numeric');
            app.missileRadiusmEditFieldLabel = uilabel(app.Panel_35);
            app.missileRadiusmEditFieldLabel.Text = {'missileRadius(m)'; '导弹半径'};
            app.missileRadiusmEditField = uieditfield(app.Panel_35, 'numeric');
            app.Panel_36 = uipanel(app.Tab_13);
            app.Panel_36.Title = '飞机尺寸';
            app.airplaneLmEditFieldLabel = uilabel(app.Panel_36);
            app.airplaneLmEditFieldLabel.Text = {'airplaneL(m)'; '飞机长方体长度'};
            app.airplaneLmEditField = uieditfield(app.Panel_36, 'numeric');
            app.airplaneWmEditFieldLabel = uilabel(app.Panel_36);
            app.airplaneWmEditFieldLabel.Text = {'airplaneW(m)'; '宽度'};
            app.airplaneWmEditField = uieditfield(app.Panel_36, 'numeric');
            app.airplaneHmEditFieldLabel = uilabel(app.Panel_36);
            app.airplaneHmEditFieldLabel.Text = {'airplaneH(m)'; '高度'};
            app.airplaneHmEditField = uieditfield(app.Panel_36, 'numeric');
            app.platformDimensionsLengthWidthHeightOriginOffsetEditField = uieditfield(app.Panel_36, 'text');
            app.platformDimensionsLengthWidthHeightOriginOffsetEditFieldLabel = uilabel(app.Panel_36);
            app.platformDimensionsLengthWidthHeightOriginOffsetEditFieldLabel.Text = {'platformDimensions'; '统一尺寸参数结构'; '(Length, Width, Height, OriginOffset)'};
            app.Panel_37 = uipanel(app.Tab_13);
            app.Panel_37.Title = '平台位置与运动学';
            app.platformHeightmEditFieldLabel = uilabel(app.Panel_37);
            app.platformHeightmEditFieldLabel.Text = {'platformHeight(m)'; '平台高度'};
            app.platformHeightmEditField = uieditfield(app.Panel_37, 'numeric');
            app.platformInitVelmsEditFieldLabel = uilabel(app.Panel_37);
            app.platformInitVelmsEditFieldLabel.Text = {'platformInitVel'; '平台初始速度(m/s，场景坐标系分量)'};
            app.platformInitVelmsEditField = uieditfield(app.Panel_37, 'numeric');
            app.platformPositionmxyzEditField = uieditfield(app.Panel_37, 'text');
            app.platformPositionmxyzEditFieldLabel = uilabel(app.Panel_37);
            app.platformPositionmxyzEditFieldLabel.Text = {'platformPosition(m)'; '平台初始位置 [x, y, z]'};
            app.platformAccBodymsEditFieldLabel = uilabel(app.Panel_37);
            app.platformAccBodymsEditFieldLabel.Text = {'platformAccBody'; '加速度(m/s²，场景坐标系)'};
            app.platformAccBodymsEditField = uieditfield(app.Panel_37, 'numeric');
            app.Panel_38 = uipanel(app.Tab_13);
            app.platformAngVelBodyradsEditFieldLabel = uilabel(app.Panel_38);
            app.platformAngVelBodyradsEditFieldLabel.Text = {'platformAngVelBody'; '角速度(rad/s，场景坐标系)'};
            app.platformAngVelBodyradsEditField = uieditfield(app.Panel_38, 'numeric');
            app.platformInitOrientationZYXEditField = uieditfield(app.Panel_38, 'text');
            app.platformInitOrientationZYXEditFieldLabel = uilabel(app.Panel_38);
            app.platformInitOrientationZYXEditFieldLabel.Text = {'platformInitOrientation'; '平台初始姿态角'; '(四元数表示，欧拉角 ZYX 顺序)'};
            app.Panel_39 = uipanel(app.Tab_13);
            app.Panel_39.Title = '平台尺寸与几何';
            app.platNameCellEditFieldLabel = uilabel(app.Panel_39);
            app.platNameCellEditFieldLabel.Text = {'platNameCell'; '平台名称存储'};
            app.platNameCellEditField = uieditfield(app.Panel_39, 'text');
            app.Tab_14 = uitab(app.TabGroup_5);
            app.Tab_14.Title = '天线装载参数';
            app.Panel_40 = uipanel(app.Tab_14);
            app.Panel_40.Title = '安装角度';
            app.mountingYPRYawPitchRollEditFieldLabel = uilabel(app.Panel_40);
            app.mountingYPRYawPitchRollEditFieldLabel.Text = {'mountingYPR'; '天线在平台坐标系下的安装欧拉角 [Yaw, Pitch, Roll]（°）'};
            app.mountingYPRYawPitchRollEditField = uieditfield(app.Panel_40, 'text');
            app.Panel_41 = uipanel(app.Tab_14);
            app.Panel_41.Title = '安装位置';
            app.mountingLocationxyzmEditFieldLabel = uilabel(app.Panel_41);
            app.mountingLocationxyzmEditFieldLabel.Text = {'mountingLocation'; '天线原点相对于平台原点的偏移量 [x, y, z]（m）'};
            app.mountingLocationxyzmEditField = uieditfield(app.Panel_41, 'text');
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '（四）雷达场景创建模块';
            app.GridLayout15 = uigridlayout(app.Tab_2);
            app.GridLayout17 = uigridlayout(app.GridLayout15);
            app.TabGroup_6 = uitabgroup(app.GridLayout17);
            app.Tab_15 = uitab(app.TabGroup_6);
            app.Tab_15.Title = '场景基本参数';
            app.GridLayout_3 = uigridlayout(app.Tab_15);
            app.UpdateRateHzEditFieldLabel = uilabel(app.GridLayout_3);
            app.UpdateRateHzEditFieldLabel.Text = {' UpdateRate'; ''; '场景中仿真更新的频率（Hz）'};
            app.UpdateRateHzEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.UpdateRateHzEditField.Value = 390.625;
            app.IsEarthCenteredDropDownLabel = uilabel(app.GridLayout_3);
            app.IsEarthCenteredDropDownLabel.Text = {'IsEarthCentered'; ''; '是否使地球中心参考系'};
            app.IsEarthCenteredDropDown = uidropdown(app.GridLayout_3);
            app.IsEarthCenteredDropDown.Items = {'True', 'False'};
            app.IsEarthCenteredDropDown.Value = 'True';
            app.StopTimesEditFieldLabel = uilabel(app.GridLayout_3);
            app.StopTimesEditFieldLabel.Text = {'StopTime'; ''; '仿真终止时间（s）'};
            app.StopTimesEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.StopTimesEditField.Value = 0.0282;
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '（五）雷达天线阵列构建模块';
            app.GridLayout18 = uigridlayout(app.Tab_7);
            app.TabGroup_7 = uitabgroup(app.GridLayout18);
            app.Tab_AntennaMount = uitab(app.TabGroup_7);
            app.Tab_AntennaMount.Title = '天线装载参数';
            app.GridLayout_4 = uigridlayout(app.Tab_AntennaMount);
            app.arrayStyleDropDown_4Label = uilabel(app.GridLayout_4);
            app.arrayStyleDropDown_4Label.Text = {'arrayStyle'; ''; '天线类型'};
            app.arrayStyleDropDown_4 = uidropdown(app.GridLayout_4);
            app.arrayStyleDropDown_4.Items = {'0：多阵元矩形天线URA', '1：多阵元线性天线ULA', '2：单个sinc天线', '3：单个高斯天线', '4：自定义天线阵列', '5：子阵', '6：十字阵'};
            app.arrayStyleDropDown_4.ItemsData = [0 1 2 3 4 5 6];
            app.arrayStyleDropDown_4.Value = 0;
            app.isShowFigDropDownLabel = uilabel(app.GridLayout_4);
            app.isShowFigDropDownLabel.Text = {'isShowFig'; ''; '是否显示可视化阵列'};
            app.isShowFigDropDown = uidropdown(app.GridLayout_4);
            app.isShowFigDropDown.Items = {'0：不显示', '1：显示'};
            app.isShowFigDropDown.ItemsData = [0 1];
            app.isShowFigDropDown.Value = 0;
            app.rdrParsLabel = uilabel(app.GridLayout_4);
            app.rdrParsLabel.Text = {'rdrPars'; ''; '输入/输出结构体'};
            app.rdrpars = uieditfield(app.GridLayout_4, 'text');
            app.rdrpars.Editable = 'off';
            app.rdrpars.Placeholder = '运行 arrayConfigureXJ 后在此显示结构体摘要';
            app.Tab_AntennaConfig = uitab(app.TabGroup_7);
            app.Tab_AntennaConfig.Title = '天线配置参数';
            app.GridLayout2 = uigridlayout(app.Tab_AntennaConfig);
            app.Panel_42 = uipanel(app.GridLayout2);
            app.Panel_42.Title = '天线方向图参数';
            app.GridLayout5 = uigridlayout(app.Panel_42);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.arrayWeightsPlot2EditFieldLabel = uilabel(app.GridLayout7);
            app.arrayWeightsPlot2EditFieldLabel.Text = {'arrayWeightsPlot2'; '天线方向图权值'};
            app.arrayWeightsPlot2EditField = uieditfield(app.GridLayout7, 'text');
            app.arrayWeightsPlot2EditField.Placeholder = '---';
            app.elemuthWidthBWEditFieldLabel = uilabel(app.GridLayout7);
            app.elemuthWidthBWEditFieldLabel.Text = {'elemuthWidthBW'; '俯仰波束宽度（°）'};
            app.elemuthWidthBWEditField = uieditfield(app.GridLayout7, 'numeric');
            app.elemuthWidthBWEditField.Limits = [0 180];
            app.elemuthWidthBWEditField.Value = 12.8;
            app.azimuthWidthBWEditField_4Label = uilabel(app.GridLayout7);
            app.azimuthWidthBWEditField_4Label.Text = {'azimuthWidthBW'; '方位波束宽度（°）'};
            app.azimuthWidthBWEditField_4 = uieditfield(app.GridLayout7, 'numeric');
            app.azimuthWidthBWEditField_4.Value = 6.4;
            app.arrayPowerGaindBCustomdBEditFieldLabel = uilabel(app.GridLayout7);
            app.arrayPowerGaindBCustomdBEditFieldLabel.Text = {'arrayPower'; 'GaindBCustom'; '自定义时的天线增益'; '（dB）'};
            app.arrayPowerGaindBCustomdBEditField = uieditfield(app.GridLayout7, 'numeric');
            app.arrayPowerGaindBCustomdBEditField.AllowEmpty = 'on';
            app.arrayPowerGaindBCustomdBEditField.Placeholder = '---';
            app.BasicParamsPanel = uipanel(app.GridLayout2);
            app.BasicParamsPanel.Title = '天线基本参数';
            app.GridLayout3 = uigridlayout(app.BasicParamsPanel);
            app.arrayElementSpacingmEditFieldLabel = uilabel(app.GridLayout3);
            app.arrayElementSpacingmEditFieldLabel.Text = {'arrayElementSpacing '; '天线间距 (m)'};
            app.arrayElementSpacingmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.arrayElementSpacingmEditField.Limits = [1e-06 Inf];
            app.arrayElementSpacingmEditField.ValueDisplayFormat = '%.4f';
            app.arrayElementSpacingmEditField.Tooltip = {'相邻阵元几何中心距离（米）'};
            app.arrayElementSpacingmEditField.Value = 0.01;
            app.arraySizePanel = uipanel(app.GridLayout3);
            app.arraySizePanel.Title = '阵元大小 arraySize';
            app.GridLayout4 = uigridlayout(app.arraySizePanel);
            app.rowsLabel = uilabel(app.GridLayout4);
            app.rowsLabel.Text = ' rows 行数 ';
            app.arrayRowsLabel = uieditfield(app.GridLayout4, 'numeric');
            app.arrayRowsLabel.Limits = [1 Inf];
            app.arrayRowsLabel.Value = 16;
            app.arrayColsLabel = uieditfield(app.GridLayout4, 'numeric');
            app.arrayColsLabel.Limits = [1 Inf];
            app.arrayColsLabel.Value = 8;
            app.colsLabel = uilabel(app.GridLayout4);
            app.colsLabel.Text = 'cols 列数';
            app.arrayAntennaNumEditFieldLabel = uilabel(app.GridLayout3);
            app.arrayAntennaNumEditFieldLabel.Text = {'arrayAntennaNum'; '阵元个数'};
            app.arrayAntennaNumEditField = uieditfield(app.GridLayout3, 'numeric');
            app.arrayAntennaNumEditField.Limits = [1 Inf];
            app.arrayAntennaNumEditField.ValueDisplayFormat = '%.0f';
            app.arrayAntennaNumEditField.Value = 128;
            app.arrayTextAreaLabel = uilabel(app.GridLayout3);
            app.arrayTextAreaLabel.Text = {'array'; '天线对象'};
            app.arrayTextArea = uitextarea(app.GridLayout3);
            app.arrayTextArea.Editable = 'off';
            app.arrayTextArea.Tooltip = {'构建成功后显示对象标识/类名'};
            app.arrayTextArea.Value = {''; 'ubarrayURA'};
            app.arrayWeightsEditFieldLabel = uilabel(app.GridLayout3);
            app.arrayWeightsEditFieldLabel.Text = {'arrayWeights'; '天线权重'};
            app.arrayWeightsEditField = uieditfield(app.GridLayout3, 'text');
            app.arrayWeightsEditField.Tooltip = {'不填则采用全1；自定义时输入与阵元数等长的权值向量'};
            app.arrayWeightsEditField.Placeholder = '全1 或 [1,1,1,...]';
            app.CheckBox = uicheckbox(app.GridLayout3);
            app.CheckBox.Tooltip = {'启用时屏蔽阵列背向辐射/响应'};
            app.CheckBox.Text = '启用';
            app.CheckBox.Value = true;
            app.BackBaffledLabel = uilabel(app.GridLayout3);
            app.BackBaffledLabel.Text = {'BackBaffled'; '后向遮蔽'};
            app.antennaElementEditFieldLabel = uilabel(app.GridLayout3);
            app.antennaElementEditFieldLabel.Text = {'antennaElement'; '天线阵元结构体'};
            app.antennaElementEditField = uieditfield(app.GridLayout3, 'text');
            app.antennaElementEditField.Editable = 'off';
            app.antennaElementEditField.Placeholder = '---';
            app.Panel_43 = uipanel(app.GridLayout2);
            app.Panel_43.Title = '子阵基本参数';
            app.GridLayout6 = uigridlayout(app.Panel_43);
            app.selLabel = uilabel(app.GridLayout6);
            app.selLabel.Text = {'sel'; ''; '子阵映射'};
            app.sel = uieditfield(app.GridLayout6, 'text');
            app.sel.Tooltip = {'arrayStyle=5 子阵时使用；映射到子阵编号'};
            app.sel.Placeholder = '如：[1,1,2,2;  ...]';
            app.Panel_44 = uipanel(app.GridLayout2);
            app.Panel_44.Title = '自定义天线时天线配置参数';
            app.GridLayout8 = uigridlayout(app.Panel_44);
            app.bwEditFieldLabel = uilabel(app.GridLayout8);
            app.bwEditFieldLabel.Text = {'bw'; '方位、俯仰波束'; '宽度向量（°）'};
            app.bwEditField = uieditfield(app.GridLayout8, 'text');
            app.bwEditField.Value = '[6.4, 12.8]';
            app.NEditFieldLabel = uilabel(app.GridLayout8);
            app.NEditFieldLabel.Text = {'N'; '阵列维度'};
            app.NEditField = uieditfield(app.GridLayout8, 'numeric');
            app.NEditField.Limits = [0 Inf];
            app.NEditField.Value = 128;
            app.lambdamEditFieldLabel = uilabel(app.GridLayout8);
            app.lambdamEditFieldLabel.Text = {'lambda'; '波长（m）'};
            app.lambdamEditField = uieditfield(app.GridLayout8, 'numeric');
            app.lambdamEditField.Value = 6.4;
            app.arrayURAEditFieldLabel = uilabel(app.GridLayout8);
            app.arrayURAEditFieldLabel.Text = {'array'; '天线对象(URA）'};
            app.arrayURAEditField = uieditfield(app.GridLayout8, 'text');
            app.arrayURAEditField.Value = 'URA';
            app.azEditFieldLabel = uilabel(app.GridLayout8);
            app.azEditFieldLabel.Text = {'az'; '方位角网络(°)'};
            app.azEditField = uieditfield(app.GridLayout8, 'text');
            app.azEditField.Value = '-180:0.4:180';
            app.elEditFieldLabel = uilabel(app.GridLayout8);
            app.elEditFieldLabel.Text = {'el'; '俯仰角网络(°)'};
            app.elEditField = uieditfield(app.GridLayout8, 'text');
            app.elEditField.Value = '-90:0.4:90';
            app.GTextAreaLabel = uilabel(app.GridLayout8);
            app.GTextAreaLabel.Text = {'G'; '方向图复数场'};
            app.GTextArea = uitextarea(app.GridLayout8);
            app.GTextArea.Placeholder = '---';
            app.mag_dBTextAreaLabel = uilabel(app.GridLayout8);
            app.mag_dBTextAreaLabel.Text = {'mag_dB'; '幅度方向图'};
            app.mag_dBTextArea = uitextarea(app.GridLayout8);
            app.mag_dBTextArea.Placeholder = '---';
            app.PTextAreaLabel = uilabel(app.GridLayout8);
            app.PTextAreaLabel.Text = {'P'; '相位方向图'};
            app.PTextArea = uitextarea(app.GridLayout8);
            app.PTextArea.Placeholder = '---';
            app.antEditFieldLabel = uilabel(app.GridLayout8);
            app.antEditFieldLabel.Text = {'ant'; '天线结构体'};
            app.antEditField = uieditfield(app.GridLayout8, 'text');
            app.antEditField.Editable = 'off';
            app.antEditField.Value = '---';
            app.Tab_16 = uitab(app.TabGroup_7);
            app.Tab_16.Title = '其他情况参数';
            app.GridLayout9 = uigridlayout(app.Tab_16);
            app.Panel_45 = uipanel(app.GridLayout9);
            app.Panel_45.Title = '设计子阵时参数';
            app.GridLayout10 = uigridlayout(app.Panel_45);
            app.subarraysNumEditFieldLabel = uilabel(app.GridLayout10);
            app.subarraysNumEditFieldLabel.Text = {'subarraysNum '; '设计子阵时'; '子阵的数量'};
            app.subarraysNumEditField = uieditfield(app.GridLayout10, 'numeric');
            app.subarraysNumEditField.Value = 128;
            app.Panel_46 = uipanel(app.GridLayout9);
            app.Panel_46.Title = '设计十字阵时参数';
            app.GridLayout11 = uigridlayout(app.Panel_46);
            app.rowsEditFieldLabel = uilabel(app.GridLayout11);
            app.rowsEditFieldLabel.Text = {'rows'; '设计十字阵时总行数'};
            app.rowsEditField = uieditfield(app.GridLayout11, 'numeric');
            app.rowsEditField.Value = 21;
            app.colsEditFieldLabel = uilabel(app.GridLayout11);
            app.colsEditFieldLabel.Text = {'cols'; '设计十字阵时总列数'};
            app.colsEditField = uieditfield(app.GridLayout11, 'numeric');
            app.colsEditField.Value = 21;
            app.horwidthEditFieldLabel = uilabel(app.GridLayout11);
            app.horwidthEditFieldLabel.Text = {'horwidth'; '中心横臂保留行数'};
            app.horwidthEditField = uieditfield(app.GridLayout11, 'numeric');
            app.horwidthEditField.Value = 7;
            app.verwidthEditFieldLabel = uilabel(app.GridLayout11);
            app.verwidthEditFieldLabel.Text = {'verwidth'; '中心竖臂保留列数'};
            app.verwidthEditField = uieditfield(app.GridLayout11, 'numeric');
            app.verwidthEditField.Value = 7;
            app.posEditFieldLabel = uilabel(app.GridLayout11);
            app.posEditFieldLabel.Text = {'pos'; '阵元三维坐标'};
            app.posEditField = uieditfield(app.GridLayout11, 'text');
            app.posEditField.Editable = 'off';
            app.posEditField.Placeholder = '---';
            app.midREditFieldLabel = uilabel(app.GridLayout11);
            app.midREditFieldLabel.Text = {'midR'; '阵元中心的行索引'};
            app.midREditField = uieditfield(app.GridLayout11, 'numeric');
            app.midREditField.Value = 11;
            app.midCEditFieldLabel = uilabel(app.GridLayout11);
            app.midCEditFieldLabel.Text = {'midC'; '阵元中心列索引'};
            app.midCEditField = uieditfield(app.GridLayout11, 'numeric');
            app.midCEditField.Value = 11;
            app.selPosEditFieldLabel = uilabel(app.GridLayout11);
            app.selPosEditFieldLabel.Text = {'selPos'; '十字阵元的阵元位置'};
            app.selPosEditField = uieditfield(app.GridLayout11, 'text');
            app.selPosEditField.Editable = 'off';
            app.selPosEditField.Placeholder = '---';
            app.selNormEditFieldLabel = uilabel(app.GridLayout11);
            app.selNormEditFieldLabel.Text = {'selNorm'; '阵元法线'};
            app.selNormEditField = uieditfield(app.GridLayout11, 'text');
            app.selNormEditField.Editable = 'off';
            app.selNormEditField.Placeholder = '---';
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = '（六）雷达平台、发射机和接收机构建模块';
            app.GridLayout12 = uigridlayout(app.Tab_8);
            app.TabGroup2 = uitabgroup(app.GridLayout12);
            app.Tab_17 = uitab(app.TabGroup2);
            app.Tab_17.Title = '雷达平台的运动轨迹';
            app.GridLayout19 = uigridlayout(app.Tab_17);
            app.platformMoveFlagDropDownLabel = uilabel(app.GridLayout19);
            app.platformMoveFlagDropDownLabel.Text = {'platformMoveFlag'; ''; '平台是否运动'};
            app.platformMoveFlagDropDown = uidropdown(app.GridLayout19);
            app.platformMoveFlagDropDown.Items = {'1：运动', '0：静止'};
            app.platformMoveFlagDropDown.Value = '1：运动';
            app.platformMoveStyleLabelDropDownLabel = uilabel(app.GridLayout19);
            app.platformMoveStyleLabelDropDownLabel.Text = {'platformMoveStyle'; ''; '运动方式'};
            app.platformMoveStyleDropDown_2 = uidropdown(app.GridLayout19);
            app.platformMoveStyleDropDown_2.Items = {'1：弹道运行轨迹', '2：匀速运动'};
            app.platformMoveStyleDropDown_2.Value = '1：弹道运行轨迹';
            app.sampleTimeIntervalsLabel = uilabel(app.GridLayout19);
            app.sampleTimeIntervalsLabel.Text = {'sampleTimeInterval'; ''; '采样时间间隔 （s）'};
            app.sampleTimeIntervalsEditField = uieditfield(app.GridLayout19, 'numeric');
            app.trajCreationTypeDropDownLabel = uilabel(app.GridLayout19);
            app.trajCreationTypeDropDownLabel.Text = {'trajCreationType'; ''; '轨迹生成方式'};
            app.trajCreationTypeDropDown = uidropdown(app.GridLayout19);
            app.trajCreationTypeDropDown.Items = {'1：waypointTrajectory（航路点轨迹）', '2：kinematicTrajectory（运动学轨迹）'};
            app.trajCreationTypeDropDown.Value = '2：kinematicTrajectory（运动学轨迹）';
            app.rdrtrajTextAreaLabel = uilabel(app.GridLayout19);
            app.rdrtrajTextAreaLabel.Text = {'rdrtraj'; ''; '雷达轨迹'};
            app.rdrtrajTextArea = uitextarea(app.GridLayout19);
            app.rdrtrajTextArea.Editable = 'off';
            app.initPosLabelxyzmEditFieldLabel = uilabel(app.GridLayout19);
            app.initPosLabelxyzmEditFieldLabel.Text = {'initPos'; ''; '初始位置 [x y z] / m'};
            app.initPosxyzmEditField = uieditfield(app.GridLayout19, 'text');
            app.initPosxyzmEditField.Value = '[0 0 1000]';
            app.initVelLabelvxvyvzEditFieldLabel = uilabel(app.GridLayout19);
            app.initVelLabelvxvyvzEditFieldLabel.Text = {'initVel'; ''; '初始速度 [vx vy vz]'};
            app.initVelvxvyvzEditField = uieditfield(app.GridLayout19, 'text');
            app.initVelvxvyvzEditField.Value = '[0 200 0]';
            app.sceneXYZMaxLabelxmaxymaxzmaxmEditFieldLabel = uilabel(app.GridLayout19);
            app.sceneXYZMaxLabelxmaxymaxzmaxmEditFieldLabel.Text = {'rdrPars.sceneXYZMaxl'; '雷达场景在x、y、z三个方向上'; '的最大边界坐标'};
            app.rdrParssceneXYZMaxlxyzEditField = uieditfield(app.GridLayout19, 'text');
            app.rdrParssceneXYZMaxlxyzEditField.Value = '[5000 5000 3000]';
            app.accAngIsBodyFrameLabelDropDownLabel = uilabel(app.GridLayout19);
            app.accAngIsBodyFrameLabelDropDownLabel.Text = {'extraInfo.AccAngIsBodyFrame'; ''; '加速度/角速度是否在机体坐标系'};
            app.extraInfoAccAngIsBodyFrameDropDown = uidropdown(app.GridLayout19);
            app.extraInfoAccAngIsBodyFrameDropDown.Items = {'1：已在机体坐标系（无需变换）', '0：需利用Orientation变换'};
            app.extraInfoAccAngIsBodyFrameDropDown.Value = '1：已在机体坐标系（无需变换）';
            app.accBodyLabelaxayazms2EditFieldLabel = uilabel(app.GridLayout19);
            app.accBodyLabelaxayazms2EditFieldLabel.Text = {'accBody'; ''; '在场景坐标系中的加速度 [ax ay az] / (m/s^2)'};
            app.accBodyaxayazms2EditField = uieditfield(app.GridLayout19, 'text');
            app.accBodyaxayazms2EditField.Value = '[0 0 0]';
            app.angVelBodyLabelxyzEditFieldLabel = uilabel(app.GridLayout19);
            app.angVelBodyLabelxyzEditFieldLabel.Text = {'angVelBody'; ''; '在场景坐标系中的角速度 [ωx ωy ωz]'};
            app.angVelBodyxyzEditField = uieditfield(app.GridLayout19, 'text');
            app.angVelBodyxyzEditField.Value = '[0 0 0]';
            app.initOrientationLabelqwqxqyqzEditFieldLabel_2 = uilabel(app.GridLayout19);
            app.initOrientationLabelqwqxqyqzEditFieldLabel_2.Text = {'initOrientation'; '初始姿态/朝向四元数 '; '（四元数或欧拉角，按项目约定格式）'};
            app.initOrientationLabelqwqxqyqzEditField_2 = uieditfield(app.GridLayout19, 'text');
            app.initOrientationLabelqwqxqyqzEditField_2.Value = '[1 0 0 0]';
            app.extraInfoOrientationLabel = uilabel(app.GridLayout19);
            app.extraInfoOrientationLabel.Text = {'extraInfo.Orientation'; ''; '附加信息中的姿态/朝向'};
            app.extraInfoOrientationEditField = uieditfield(app.GridLayout19, 'text');
            app.extraInfoAccelerationEditFieldLabel = uilabel(app.GridLayout19);
            app.extraInfoAccelerationEditFieldLabel.Text = {'extraInfo.Acceleration'; ''; '附加信息中的加速度'};
            app.extraInfoAccelerationEditField = uieditfield(app.GridLayout19, 'text');
            app.rdrtrajextraInfoEditFieldLabel = uilabel(app.GridLayout19);
            app.rdrtrajextraInfoEditFieldLabel.Text = {'rdrtraj.extraInfo'; ''; '雷达轨迹的附加信息'};
            app.rdrtrajextraInfoEditField = uieditfield(app.GridLayout19, 'text');
            app.Tab_11 = uitab(app.TabGroup2);
            app.Tab_11.Title = '雷达平台';
            app.GridLayout_5 = uigridlayout(app.Tab_11);
            app.Panel_47 = uipanel(app.GridLayout_5);
            app.Panel_47.Title = '创建雷达平台';
            app.GridLayout20 = uigridlayout(app.Panel_47);
            app.rdrParsrdrPlatClassIDClassIDLabel = uilabel(app.GridLayout20);
            app.rdrParsrdrPlatClassIDClassIDLabel.Text = {'rdrPars.rdrPlatClassID'; '雷达平台ClassID'};
            app.rdrParsrdrPlatClassIDClassIDEditField = uieditfield(app.GridLayout20, 'text');
            app.rdrParsrdrPlatClassIDClassIDEditField.Value = '0';
            app.useTrajectoryLabelTextDropDownLabel = uilabel(app.GridLayout20);
            app.useTrajectoryLabelTextDropDownLabel.Text = {'useTrajectoryLabel'; 'Text：平台位置来源'};
            app.useTrajectoryLabelTextDropDown = uidropdown(app.GridLayout20);
            app.useTrajectoryLabelTextDropDown.Items = {'使用轨迹（rdrPars.rdrtraj）', '使用固定位置'};
            app.useTrajectoryLabelTextDropDown.Value = '使用轨迹（rdrPars.rdrtraj）';
            app.fixedPositionLabelxyzEditFieldLabel = uilabel(app.GridLayout20);
            app.fixedPositionLabelxyzEditFieldLabel.Text = {'fixedPositionLabel'; '静止平台位置 [x y z]'};
            app.fixedPositionLabelxyzEditField = uieditfield(app.GridLayout20, 'text');
            app.fixedPositionLabelxyzEditField.Value = '[0 0 0]';
            app.rdrParsplatformDimensionsLWHEditFieldLabel = uilabel(app.GridLayout20);
            app.rdrParsplatformDimensionsLWHEditFieldLabel.Text = {'rdrPars.platformDimensions'; '雷达平台尺寸 [L W H]'};
            app.rdrParsplatformDimensionsLWHEditField = uieditfield(app.GridLayout20, 'text');
            app.DPanel = uipanel(app.GridLayout_5);
            app.DPanel.Title = '设置雷达平台的 3D 模型及颜色';
            app.GridLayout21 = uigridlayout(app.DPanel);
            app.platformSizeStyleDropDown_2Label = uilabel(app.GridLayout21);
            app.platformSizeStyleDropDown_2Label.Text = {'platformSizeStyle'; '平台尺寸类型'};
            app.platformSizeStyleDropDown_2 = uidropdown(app.GridLayout21);
            app.platformSizeStyleDropDown_2.Items = {'1：点目标', '2：分布式目标'};
            app.platformSizeStyleDropDown_2.Value = '1：点目标';
            app.mesh3EditFieldLabel = uilabel(app.GridLayout21);
            app.mesh3EditFieldLabel.Text = {'mesh'; '平台的 3 维模型'};
            app.mesh3EditField = uieditfield(app.GridLayout21, 'text');
            app.V3EditFieldLabel = uilabel(app.GridLayout21);
            app.V3EditFieldLabel.Text = {'V'; '平台 3 维模型顶点坐标矩阵'};
            app.V3EditField = uieditfield(app.GridLayout21, 'text');
            app.F3EditFieldLabel = uilabel(app.GridLayout21);
            app.F3EditFieldLabel.Text = {'F'; '平台 3 维模型面索引矩阵'};
            app.F3EditField = uieditfield(app.GridLayout21, 'text');
            app.RMLabel = uilabel(app.GridLayout21);
            app.RMLabel.Text = {'RM'; '复合旋转矩阵'};
            app.RMEditField = uieditfield(app.GridLayout21, 'text');
            app.RMEditField.Editable = 'off';
            app.rdrplatMeshVertices3EditFieldLabel = uilabel(app.GridLayout21);
            app.rdrplatMeshVertices3EditFieldLabel.Text = {'rdrplat.Mesh.Vertices'; '旋转后3维模型顶点坐标'};
            app.rdrplatMeshVertices3EditField = uieditfield(app.GridLayout21, 'text');
            app.rdrplatMeshVertices3EditField.Editable = 'off';
            app.rdrplatPlatOtherParscolorProportionEditFieldLabel = uilabel(app.GridLayout21);
            app.rdrplatPlatOtherParscolorProportionEditFieldLabel.Text = {'rdrplat.PlatOtherPars.colorProportion'; '网格对象表面颜色分布的比例'};
            app.rdrplatPlatOtherParscolorProportionEditField = uieditfield(app.GridLayout21, 'text');
            app.rdrplatPlatOtherParscolorProportionEditField.Value = '[0 0.15 1]';
            app.rdrplatPlatOtherParscolorShowEditFieldLabel = uilabel(app.GridLayout21);
            app.rdrplatPlatOtherParscolorShowEditFieldLabel.Text = {'rdrplat.PlatOtherPars.colorShow'; '平台网格的颜色配置表'};
            app.rdrplatPlatOtherParscolorShowEditField = uieditfield(app.GridLayout21, 'text');
            app.rdrplatPlatOtherParscurIndexLabel = uilabel(app.GridLayout21);
            app.rdrplatPlatOtherParscurIndexLabel.Text = {'rdrplat.PlatOtherPars.curIndex'; '“当前索引”标记'};
            app.rdrplatPlatOtherParscurIndexEditField = uieditfield(app.GridLayout21, 'text');
            app.rdrplatPlatOtherParsnameStrEditFieldLabel = uilabel(app.GridLayout21);
            app.rdrplatPlatOtherParsnameStrEditFieldLabel.Text = {'rdrplat.PlatOtherPars.nameStr'; '雷达平台名称'};
            app.rdrplatPlatOtherParsnameStrEditField = uieditfield(app.GridLayout21, 'text');
            app.Tab_19 = uitab(app.TabGroup2);
            app.Tab_19.Title = '雷达发射机';
            app.GridLayout24 = uigridlayout(app.Tab_19);
            app.iqsensorIQPanel = uipanel(app.GridLayout24);
            app.iqsensorIQPanel.Title = 'iqsensor   IQ 传感器与波形参数';
            app.GridLayout25 = uigridlayout(app.iqsensorIQPanel);
            app.iqsensorNumRepetitionsEditFieldLabel = uilabel(app.GridLayout25);
            app.iqsensorNumRepetitionsEditFieldLabel.Text = {'iqsensor.NumRepetitions'; '重复采集次数'; '（每次扫描采集多少个脉冲）'};
            app.iqsensorNumRepetitionsEditField = uieditfield(app.GridLayout25, 'numeric');
            app.iqsensorRangeOutputPortDropDownLabel = uilabel(app.GridLayout25);
            app.iqsensorRangeOutputPortDropDownLabel.Text = {'iqsensor.RangeOutputPort'; '是否输出距离端口'};
            app.iqsensorRangeOutputPortDropDown = uidropdown(app.GridLayout25);
            app.iqsensorRangeOutputPortDropDown.Items = {'true', 'false'};
            app.iqsensorRangeOutputPortDropDown.Value = 'true';
            app.iqsensorTimeOutputPortDropDownLabel = uilabel(app.GridLayout25);
            app.iqsensorTimeOutputPortDropDownLabel.Text = {'iqsensor.TimeOutputPort'; '是否输出时间向量端口'};
            app.iqsensorTimeOutputPortDropDown = uidropdown(app.GridLayout25);
            app.iqsensorTimeOutputPortDropDown.Items = {'true', 'false'};
            app.iqsensorTimeOutputPortDropDown.Value = 'true';
            app.iqsensorprecisionDigitEditFieldLabel = uilabel(app.GridLayout25);
            app.iqsensorprecisionDigitEditFieldLabel.Text = {'iqsensor.precisionDigit'; '输出数据精度（位数）'};
            app.iqsensorprecisionDigitEditField = uieditfield(app.GridLayout25, 'numeric');
            app.iqsensorWaveformLFMEditFieldLabel = uilabel(app.GridLayout25);
            app.iqsensorWaveformLFMEditFieldLabel.Text = {'iqsensor.Waveform'; 'LFM 波形对象'; '（由前面参数生成）'};
            app.iqsensorWaveformLFMEditField = uieditfield(app.GridLayout25, 'text');
            app.originalLFMWaveEditFieldLabel = uilabel(app.GridLayout25);
            app.originalLFMWaveEditFieldLabel.Text = {'originalLFMWave'; '原始线性调频波形的副本'};
            app.originalLFMWaveEditField = uieditfield(app.GridLayout25, 'text');
            app.originalLFMWaveEditField.Editable = 'off';
            app.rdrParsoriginalLFMWaveLabel = uilabel(app.GridLayout25);
            app.rdrParsoriginalLFMWaveLabel.Text = {'rdrPars.originalLFM Wave'; ' 在雷达参数中存储发射波形'};
            app.rdrParsoriginalLFMWaveEditField = uieditfield(app.GridLayout25, 'text');
            app.rdrParsoriginalLFMWaveEditField.Editable = 'off';
            app.rdrParsoriginalLFMSignalPRTLabel = uilabel(app.GridLayout25);
            app.rdrParsoriginalLFMSignalPRTLabel.Text = {'rdrPars.originalLFMSignal'; '从一个 PRT 内提取的'; '原始线性调频脉冲信号'};
            app.rdrParsoriginalLFMSignalPRTEditField = uieditfield(app.GridLayout25, 'text');
            app.rdrParsoriginalLFMSignalPRTEditField.Editable = 'off';
            app.Panel_48 = uipanel(app.GridLayout24);
            app.Panel_48.Title = '发射机参数';
            app.GridLayout26 = uigridlayout(app.Panel_48);
            app.iqsensorTransmitterPeakPowerWEditField_2Label = uilabel(app.GridLayout26);
            app.iqsensorTransmitterPeakPowerWEditField_2Label.Text = {'iqsensor.Transmitter.PeakPower'; '发射机峰值功率 / W'};
            app.iqsensorTransmitterPeakPowerWEditField_2 = uieditfield(app.GridLayout26, 'numeric');
            app.iqsensorTransmitterNoiseMethodDropDownLabel = uilabel(app.GridLayout26);
            app.iqsensorTransmitterNoiseMethodDropDownLabel.Text = {'iqsensor.Transmitter.NoiseMethod'; '发射机噪声建模方式'};
            app.iqsensorTransmitterNoiseMethodDropDown = uidropdown(app.GridLayout26);
            app.iqsensorTransmitterNoiseMethodDropDown.Items = {'Noise power', 'kTBF'};
            app.iqsensorTransmitterNoiseMethodDropDown.Value = 'Noise power';
            app.iqsensorTransmitAntennaOperatingFrequencyHzLabel = uilabel(app.GridLayout26);
            app.iqsensorTransmitAntennaOperatingFrequencyHzLabel.Text = {'iqsensor.TransmitAntenna.'; 'OperatingFrequency'; '发射机工作频率 / Hz'};
            app.iqsensorTransmitAntennaOperatingFrequencyHzEditField = uieditfield(app.GridLayout26, 'numeric');
            app.iqsensorTransmitAntennaOperatingFrequencyHzEditField.Editable = 'off';
            app.iqsensorTransmitterGaindBEditFieldLabel = uilabel(app.GridLayout26);
            app.iqsensorTransmitterGaindBEditFieldLabel.Text = {'iqsensor.Transmitter.Gain'; '发射机增益 / dB'};
            app.iqsensorTransmitterGaindBEditField = uieditfield(app.GridLayout26, 'numeric');
            app.DropDownLabel = uilabel(app.GridLayout26);
            app.DropDownLabel.Text = {'iqsensor.Transmitter.'; 'CoherentOnTransmit'; '是否相干发射'};
            app.iqsensorTransmitterCoherentOnTransmitDropDown = uidropdown(app.GridLayout26);
            app.iqsensorTransmitterCoherentOnTransmitDropDown.Items = {'true', 'false'};
            app.iqsensorTransmitterCoherentOnTransmitDropDown.Value = 'true';
            app.iqsensorTransmitAntennaSensorLabel = uilabel(app.GridLayout26);
            app.iqsensorTransmitAntennaSensorLabel.Text = {'iqsensor.TransmitAntenna.'; 'Sensor'; '发射天线阵列对象'};
            app.iqsensorTransmitAntennaSensorEditField = uieditfield(app.GridLayout26, 'text');
            app.iqsensorTransmitAntennaSensorEditField.Editable = 'off';
            app.iqsensorTransmitAntennaWeightsInputPortLabel = uilabel(app.GridLayout26);
            app.iqsensorTransmitAntennaWeightsInputPortLabel.Text = {'iqsensor.TransmitAntenna.'; 'WeightsInputPort'; '是否从外部输入加权向量'};
            app.iqsensorTransmitAntennaWeightsInputPortDropDown = uidropdown(app.GridLayout26);
            app.iqsensorTransmitAntennaWeightsInputPortDropDown.Items = {'true', 'false'};
            app.iqsensorTransmitAntennaWeightsInputPortDropDown.Value = 'true';
            app.Tab_20 = uitab(app.TabGroup2);
            app.Tab_20.Title = '接收机与电子扫描参数';
            app.GridLayout27 = uigridlayout(app.Tab_20);
            app.Panel_50 = uipanel(app.GridLayout27);
            app.Panel_50.Title = '接收机基本参数';
            app.GridLayout27_2 = uigridlayout(app.Panel_50);
            app.iqsensorReceiverSampleRateHzEditField_2Label = uilabel(app.GridLayout27_2);
            app.iqsensorReceiverSampleRateHzEditField_2Label.Text = {'iqsensor.Receiver.SampleRate'; '接收机采样率 / Hz'};
            app.iqsensorReceiverSampleRateHzEditField_2 = uieditfield(app.GridLayout27_2, 'numeric');
            app.iqsensorReceiveAntennaOperatingFrequencyHzEditField_2Label = uilabel(app.GridLayout27_2);
            app.iqsensorReceiveAntennaOperatingFrequencyHzEditField_2Label.Text = {'iqsensor.ReceiveAntenna.'; 'OperatingFrequency'; '接收机载频 / Hz'};
            app.iqsensorReceiveAntennaOperatingFrequencyHzEditField_2 = uieditfield(app.GridLayout27_2, 'numeric');
            app.iqsensorReceiveAntennaOperatingFrequencyHzEditField_2.Editable = 'off';
            app.iqsensorReceiverLossFactorEditField_2Label = uilabel(app.GridLayout27_2);
            app.iqsensorReceiverLossFactorEditField_2Label.Text = {'iqsensor.Receiver.LossFactor'; '接收机链路损耗因子'};
            app.iqsensorReceiverLossFactorEditField_2 = uieditfield(app.GridLayout27_2, 'numeric');
            app.rdrParsreceiverNoiseStyleFlagDropDown_2Label = uilabel(app.GridLayout27_2);
            app.rdrParsreceiverNoiseStyleFlagDropDown_2Label.Text = {'rdrPars.receiverNoiseStyleFlag'; '噪声建模开关'};
            app.rdrParsreceiverNoiseStyleFlagDropDown_2 = uidropdown(app.GridLayout27_2);
            app.rdrParsreceiverNoiseStyleFlagDropDown_2.Items = {'0=噪声功率', '1=kBT'};
            app.rdrParsreceiverNoiseStyleFlagDropDown_2.Value = '0=噪声功率';
            app.iqsensorReceiverNoiseMethodDropDown_2Label = uilabel(app.GridLayout27_2);
            app.iqsensorReceiverNoiseMethodDropDown_2Label.Text = {'iqsensor.Receiver.NoiseMethod'; '接收机噪声建模方式'};
            app.iqsensorReceiverNoiseMethodDropDown_2 = uidropdown(app.GridLayout27_2);
            app.iqsensorReceiverNoiseMethodDropDown_2.Items = {'Noise power', 'kBT'};
            app.iqsensorReceiverNoiseMethodDropDown_2.Value = 'Noise power';
            app.iqsensorReceiverReferenceTemperatureKEditField_2Label = uilabel(app.GridLayout27_2);
            app.iqsensorReceiverReferenceTemperatureKEditField_2Label.Text = {'iqsensor.Receiver.'; 'ReferenceTemperature'; '接收机参考温度 / K'};
            app.iqsensorReceiverReferenceTemperatureKEditField_2 = uieditfield(app.GridLayout27_2, 'numeric');
            app.iqsensorReceiverNoiseFiguredBEditFieldLabel = uilabel(app.GridLayout27_2);
            app.iqsensorReceiverNoiseFiguredBEditFieldLabel.Text = {'iqsensor.Receiver.NoiseFigure'; '热噪声系数 / dB'};
            app.iqsensorReceiverNoiseFiguredBEditField = uieditfield(app.GridLayout27_2, 'numeric');
            app.iqsensorReceiverNoisePowerWEditFieldLabel = uilabel(app.GridLayout27_2);
            app.iqsensorReceiverNoisePowerWEditFieldLabel.Text = {'iqsensor.Receiver.NoisePower'; '噪声功率 / W'};
            app.iqsensorReceiverNoisePowerWEditField = uieditfield(app.GridLayout27_2, 'numeric');
            app.iqsensorReceiverGaindBEditFieldLabel = uilabel(app.GridLayout27_2);
            app.iqsensorReceiverGaindBEditFieldLabel.Text = {'iqsensor.Receiver.Gain'; '接收机增益 / dB'};
            app.iqsensorReceiverGaindBEditField = uieditfield(app.GridLayout27_2, 'numeric');
            app.iqsensorReceiveAntennaSensorEditFieldLabel = uilabel(app.GridLayout27_2);
            app.iqsensorReceiveAntennaSensorEditFieldLabel.Text = {'iqsensor.ReceiveAntenna.Sensor'; '接收天线阵列对象'};
            app.iqsensorReceiveAntennaSensorEditField = uieditfield(app.GridLayout27_2, 'text');
            app.iqsensorReceiveAntennaSensorEditField.Editable = 'off';
            app.iqsensorReceiveAntennaWeightsInputPortDropDownLabel = uilabel(app.GridLayout27_2);
            app.iqsensorReceiveAntennaWeightsInputPortDropDownLabel.Text = {'iqsensor.ReceiveAntenna.WeightsInputPort'; '是否从外部输入加权向量'};
            app.iqsensorReceiveAntennaWeightsInputPortDropDown = uidropdown(app.GridLayout27_2);
            app.iqsensorReceiveAntennaWeightsInputPortDropDown.Items = {'true', 'false'};
            app.iqsensorReceiveAntennaWeightsInputPortDropDown.Value = 'true';
            app.Panel_51 = uipanel(app.GridLayout27);
            app.Panel_51.Title = '电子扫描参数';
            app.GridLayout28 = uigridlayout(app.Panel_51);
            app.iqsensorElectronicScanModeDropDownLabel = uilabel(app.GridLayout28);
            app.iqsensorElectronicScanModeDropDownLabel.Text = {'iqsensor.ElectronicScanMode'; '电子扫描模式'};
            app.iqsensorElectronicScanModeDropDown = uidropdown(app.GridLayout28);
            app.iqsensorElectronicScanModeDropDown.Items = {'None', 'Sector', 'Custom'};
            app.iqsensorElectronicScanModeDropDown.Value = 'None';
            app.iqsensorElectronicScanLimitsEditFieldLabel = uilabel(app.GridLayout28);
            app.iqsensorElectronicScanLimitsEditFieldLabel.Text = {'iqsensor.ElectronicScanLimits'; '电子扫描范围 '};
            app.iqsensorElectronicScanLimitsEditField = uieditfield(app.GridLayout28, 'text');
            app.iqsensorElectronicScanLimitsEditField.Value = '[azMin azMax; elMin elMax]';
            app.iqsensorElectronicScanRatedegsEditFieldLabel = uilabel(app.GridLayout28);
            app.iqsensorElectronicScanRatedegsEditFieldLabel.Text = {'iqsensor.ElectronicScanRate'; '电子扫描速率   (deg/s)'};
            app.iqsensorElectronicScanRatedegsEditField = uieditfield(app.GridLayout28, 'text');
            app.iqsensorElectronicScanRatedegsEditField.Value = '[azRate; elRate]';
            app.iqsensorisElectronicSectorCPIFreezeCPILabel = uilabel(app.GridLayout28);
            app.iqsensorisElectronicSectorCPIFreezeCPILabel.Text = {'iqsensor.'; 'isElectronicSectorCPIFreeze'; '一个 CPI 内是否固定波束方向'};
            app.iqsensorisElectronicSectorCPIFreezeCPIDropDown = uidropdown(app.GridLayout28);
            app.iqsensorisElectronicSectorCPIFreezeCPIDropDown.Items = {'true', 'false'};
            app.iqsensorisElectronicSectorCPIFreezeCPIDropDown.Value = 'true';
            app.Tab_9 = uitab(app.TabGroup);
            app.Tab_9.Title = '（七）地面构建模块';
            app.GridLayout22 = uigridlayout(app.Tab_9);
            app.TabGroup3 = uitabgroup(app.GridLayout22);
            app.Tab_18 = uitab(app.TabGroup3);
            app.Tab_18.Title = '地面构建模块';
            app.GridLayout23 = uigridlayout(app.Tab_18);
            app.operatingFrequencyHzEditFieldLabel = uilabel(app.GridLayout23);
            app.operatingFrequencyHzEditFieldLabel.Text = {'operatingFrequency'; ''; '雷达系统的工作频率（Hz）'};
            app.operatingFrequencyHzEditField = uieditfield(app.GridLayout23, 'numeric');
            app.gammaDBGammadBLabel = uilabel(app.GridLayout23);
            app.gammaDBGammadBLabel.Text = {'gammaDB'; ''; '伽马值（Gamma）的分贝（dB）表示形式'; '与陆地表面归一化雷达截面积有关'};
            app.gammaDBGammadBEditField = uieditfield(app.GridLayout23, 'numeric');
            app.landParsreflLandLabel = uilabel(app.GridLayout23);
            app.landParsreflLandLabel.Text = {'landPars.reflLand'; '陆地表面后向散射系数'};
            app.landParsreflLandEditField = uieditfield(app.GridLayout23, 'numeric');
            app.landParslandSurfaceBoundaryEditFieldLabel = uilabel(app.GridLayout23);
            app.landParslandSurfaceBoundaryEditFieldLabel.Text = {'landPars.landSurfaceBoundary'; '陆地表面空间范围'};
            app.landParslandSurfaceBoundaryEditField = uieditfield(app.GridLayout23, 'numeric');
            app.landParslandSurfaceWidthEditFieldLabel = uilabel(app.GridLayout23);
            app.landParslandSurfaceWidthEditFieldLabel.Text = {'landPars.landSurfaceWidth'; '陆地表面宽度'};
            app.landParslandSurfaceWidthEditField = uieditfield(app.GridLayout23, 'numeric');
            app.landParslandSurfaceHeightEditFieldLabel = uilabel(app.GridLayout23);
            app.landParslandSurfaceHeightEditFieldLabel.Text = {'landPars.landSurfaceHeight'; '陆地表面高度'};
            app.landParslandSurfaceHeightEditField = uieditfield(app.GridLayout23, 'numeric');
            app.landParsresSurfaceEditFieldLabel = uilabel(app.GridLayout23);
            app.landParsresSurfaceEditFieldLabel.Text = {'landPars.resSurface'; '陆地表面分辨率'};
            app.landParsresSurfaceEditField = uieditfield(app.GridLayout23, 'numeric');
            app.terrainEditFieldLabel = uilabel(app.GridLayout23);
            app.terrainEditFieldLabel.Text = {'terrain'; '地形'};
            app.terrainEditField = uieditfield(app.GridLayout23, 'text');
            app.reflectModelEditFieldLabel = uilabel(app.GridLayout23);
            app.reflectModelEditFieldLabel.Text = {'reflectModel'; '陆地表面反射模型'};
            app.reflectModelEditField = uieditfield(app.GridLayout23, 'text');
            app.landParsreflectModellandParsEditFieldLabel = uilabel(app.GridLayout23);
            app.landParsreflectModellandParsEditFieldLabel.Text = {'landPars.reflectModel'; '在结构体landPars中存储陆地表面反射模型'};
            app.landParsreflectModellandParsEditField = uieditfield(app.GridLayout23, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '参数传递';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end